classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        ConvergencetolerancesMenu       matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        AutoforkMenu                    matlab.ui.container.Menu
        MainPanel                       matlab.ui.container.Panel
        MainGrid                        matlab.ui.container.GridLayout
        SimulatePanel                   matlab.ui.container.Panel
        SimulateGrid                    matlab.ui.container.GridLayout
        GVDField                        matlab.ui.control.NumericEditField
        GVDsignEditFieldLabel           matlab.ui.control.Label
        ParallelExecution               matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        TotalSystemsField               matlab.ui.control.NumericEditField
        TotalsystemsEditFieldLabel      matlab.ui.control.Label
        ActiveSystemsField              matlab.ui.control.NumericEditField
        ActivesystemsEditField_2Label   matlab.ui.control.Label
        SelectedSystemsField            matlab.ui.control.NumericEditField
        SelectedsystemsLabel            matlab.ui.control.Label
        TotalBudgetField                matlab.ui.control.NumericEditField
        TotalbudgetLabel                matlab.ui.control.Label
        BatchTypeField                  matlab.ui.control.EditField
        BatchtypeEditFieldLabel         matlab.ui.control.Label
        ViewPanel                       matlab.ui.container.Panel
        ViewLegend                      matlab.ui.control.CheckBox
        ViewLines                       matlab.ui.control.CheckBox
        ViewMarkerSize                  matlab.ui.control.Spinner
        MarkersizeLabel                 matlab.ui.control.Label
        ZoomButton                      matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        PlaybackPanel                   matlab.ui.container.Panel
        Autoplay                        matlab.ui.control.CheckBox
        PlayButton                      matlab.ui.control.Button
        PlaybackSpeed                   matlab.ui.control.Spinner
        SpeedSpinnerLabel               matlab.ui.control.Label
        SysViewB                        matlab.ui.container.TabGroup
        FieldTabB                       matlab.ui.container.Tab
        FieldGridB                      matlab.ui.container.GridLayout
        FieldAxesB                      matlab.ui.control.UIAxes
        SpectrumTabB                    matlab.ui.container.Tab
        SpectrumGridB                   matlab.ui.container.GridLayout
        SpectrumAxesB                   matlab.ui.control.UIAxes
        CWRefTabB                       matlab.ui.container.Tab
        CWRefGridB                      matlab.ui.container.GridLayout
        CWRefAxesB                      matlab.ui.control.UIAxes
        ConvergenceTabB                 matlab.ui.container.Tab
        ConvergenceGridB                matlab.ui.container.GridLayout
        ConvergenceAxesB                matlab.ui.control.UIAxes
        SystemTable                     matlab.ui.control.Table
        SysViewA                        matlab.ui.container.TabGroup
        FieldTabA                       matlab.ui.container.Tab
        FieldGridA                      matlab.ui.container.GridLayout
        FieldAxesA                      matlab.ui.control.UIAxes
        SpectrumTabA                    matlab.ui.container.Tab
        SpectrumGridA                   matlab.ui.container.GridLayout
        SpectrumAxesA                   matlab.ui.control.UIAxes
        CWRefTabA                       matlab.ui.container.Tab
        CWRefGridA                      matlab.ui.container.GridLayout
        CWRefAxesA                      matlab.ui.control.UIAxes
        ConvergenceTabA                 matlab.ui.container.Tab
        ConvergenceGridA                matlab.ui.container.GridLayout
        ConvergenceAxesA                matlab.ui.control.UIAxes
        DisplayPanel                    matlab.ui.container.Panel
        DisplayGrid                     matlab.ui.container.GridLayout
        ModesPanel                      matlab.ui.container.Panel
        ModesGrid                       matlab.ui.container.GridLayout
        DisplayBlueModes                matlab.ui.control.Spinner
        BluemodesSpinnerLabel           matlab.ui.control.Label
        DisplayRedModes                 matlab.ui.control.Spinner
        RedmodesSpinnerLabel            matlab.ui.control.Label
        DisplayCW                       matlab.ui.control.CheckBox
        DisplaySweep                    matlab.ui.control.CheckBox
        DisplayStationary               matlab.ui.control.CheckBox
        DisplayFluctuating              matlab.ui.control.CheckBox
        DisplayMultistability           matlab.ui.control.CheckBox
        DisplayMI                       matlab.ui.control.CheckBox
        EditPanel                       matlab.ui.container.Panel
        EditGrid                        matlab.ui.container.GridLayout
        EditBudget                      matlab.ui.control.NumericEditField
        BudgetincLabel                  matlab.ui.control.Label
        BudgetButton                    matlab.ui.control.Button
        EditNoise                       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        NoiseButton                     matlab.ui.control.Button
        FreezeButton                    matlab.ui.control.Button
        UnfreezeButton                  matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        ForkPanel                       matlab.ui.container.Panel
        ForkGrid                        matlab.ui.container.GridLayout
        ForkFigButton                   matlab.ui.control.StateButton
        ForkButton                      matlab.ui.control.Button
        NoiseEditField_2Label           matlab.ui.control.Label
        ForkNoiseField                  matlab.ui.control.NumericEditField
        PumpDeltaField                  matlab.ui.control.NumericEditField
        PumpdeltaEditFieldLabel         matlab.ui.control.Label
        DetunDeltaField                 matlab.ui.control.NumericEditField
        DetundeltaEditFieldLabel        matlab.ui.control.Label
        SweepseriesEditField_2Label     matlab.ui.control.Label
        ForkSweepSeriesField            matlab.ui.control.NumericEditField
        FreezelimitEditField_2Label     matlab.ui.control.Label
        ForkFreezeLimField              matlab.ui.control.NumericEditField
        BudgetEditField_2Label          matlab.ui.control.Label
        ForkBudgetField                 matlab.ui.control.NumericEditField
        FieldbuffersizeEditField_2Label  matlab.ui.control.Label
        ForkBufferSizeField             matlab.ui.control.NumericEditField
        TimewindowpointsEditField_2Label  matlab.ui.control.Label
        ForkWindowSizeField             matlab.ui.control.NumericEditField
        TimewindowsizeLabel_2           matlab.ui.control.Label
        ForkTimePointsField             matlab.ui.control.NumericEditField
        ForkStepsRoundtripField         matlab.ui.control.NumericEditField
        StepsperroundtripEditField_2Label  matlab.ui.control.Label
        ForkRoundtripsSeriesField       matlab.ui.control.NumericEditField
        RoundtripsperseriesEditField_2Label  matlab.ui.control.Label
        CreatePanel                     matlab.ui.container.Panel
        CreateGrid                      matlab.ui.container.GridLayout
        NoisePanel                      matlab.ui.container.Panel
        NoiseGrid                       matlab.ui.container.GridLayout
        InitNoiseField                  matlab.ui.control.NumericEditField
        InitnoiseEditFieldLabel         matlab.ui.control.Label
        PulsePanel                      matlab.ui.container.Panel
        PulseGrid                       matlab.ui.container.GridLayout
        ChirpField                      matlab.ui.control.NumericEditField
        ChirpEditFieldLabel             matlab.ui.control.Label
        PeakPowerField                  matlab.ui.control.NumericEditField
        PeakpowerEditFieldLabel         matlab.ui.control.Label
        FWHMField                       matlab.ui.control.NumericEditField
        FWHMEditFieldLabel              matlab.ui.control.Label
        CreateButton                    matlab.ui.control.Button
        NoiseEditFieldLabel             matlab.ui.control.Label
        NoiseField                      matlab.ui.control.NumericEditField
        FigButtonEnd                    matlab.ui.control.StateButton
        FigButtonStart                  matlab.ui.control.StateButton
        PumpEndField                    matlab.ui.control.NumericEditField
        PumpStartField                  matlab.ui.control.NumericEditField
        PumprangeEditFieldLabel         matlab.ui.control.Label
        DetunEndField                   matlab.ui.control.NumericEditField
        DetunStartField                 matlab.ui.control.NumericEditField
        DetunrangeEditFieldLabel        matlab.ui.control.Label
        SweepseriesEditFieldLabel       matlab.ui.control.Label
        SweepSeriesField                matlab.ui.control.NumericEditField
        FreezelimitEditFieldLabel       matlab.ui.control.Label
        FreezeLimitField                matlab.ui.control.NumericEditField
        BudgetEditFieldLabel            matlab.ui.control.Label
        BudgetField                     matlab.ui.control.NumericEditField
        FieldbuffersizeEditFieldLabel   matlab.ui.control.Label
        BufferSizeField                 matlab.ui.control.NumericEditField
        TimewindowsizeLabel             matlab.ui.control.Label
        WindowSizeField                 matlab.ui.control.NumericEditField
        TimewindowpointsEditFieldLabel  matlab.ui.control.Label
        TimePointsField                 matlab.ui.control.NumericEditField
        StepsRoundtripField             matlab.ui.control.NumericEditField
        StepsperroundtripEditFieldLabel  matlab.ui.control.Label
        RoundtripsSeriesField           matlab.ui.control.NumericEditField
        RoundtripsperseriesLabel        matlab.ui.control.Label
        InitField                       matlab.ui.control.DropDown
        InitialfieldLabel               matlab.ui.control.Label
        ParameterMap                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        batch               % Stores system structures and common data
        mi_handle           % Graphical region for MI
        bistab_handle       % Graphical region for bistability
        blue_mode_handles   % Bistability regions of blue detuned modes
        red_mode_handles    % Bistability regions of red detuned modes
        sys_handles         % Plotted systems
        map_legend          % Parameter map legend
        sys_brush           % Brush for highlighted systems in plot
        selected            % IDs of highlighted systems
        par_pool            % Matlab parallel pool
        cache               % Cached system data (AoS -> SoA)
        frame_timer         % Triggers side panel animations
        frame_iter_A        % Panel A iterators for requested animation frames
        frame_iter_B        % Panel B iterators for requested animation frames
        autofork_options    % Fork options returned from autofork tool

        sys_states     = {'sweep', 'fluctuating', 'cw', 'stationary'};
        state_col      = {'#4DBEEE', '#0072BD', '#7E2F8E', '#77AC30'};
        budget_markers = {'^', 'o', 's'};
        
    end

    % Methods accessible to dialog boxes
    methods (Access = public)

        % Initialize application after new batch is loaded or created
        function init(app)

            app.frame_timer = [];
            cla(app.ParameterMap)
            hold(app.ParameterMap, 'on')
            format shortE

            cla(app.FieldAxesA), cla(app.SpectrumAxesA), cla(app.CWRefAxesA), cla(app.ConvergenceAxesA)
            cla(app.FieldAxesB), cla(app.SpectrumAxesB), cla(app.CWRefAxesB), cla(app.ConvergenceAxesB)

            % Draw MI region
            app.mi_handle = gobjects(0);
            if ~isempty(app.batch.mi_bounds)
                app.mi_handle = fill(app.ParameterMap, ...
                    app.batch.mi_bounds(1, :), ...
                    app.batch.mi_bounds(2, :), ...
                    [0.3010 0.7450 0.9330], ...
                    'EdgeColor', 'none', ...
                    'FaceAlpha', 0.5);
            end

            % Draw bistability region
            app.bistab_handle = gobjects(0);
            if ~isempty(app.batch.bistab_bounds)
                app.bistab_handle = fill(app.ParameterMap, ...
                    app.batch.bistab_bounds(1, :), ...
                    app.batch.bistab_bounds(2, :), ...
                    [0 0.4470 0.7410], ...
                    'EdgeColor', 'none', ...
                    'FaceAlpha', 0.5);
            end

            % Draw adjacent mode bistability regions
            app.blue_mode_handles = gobjects(app.batch.folds - 1);
            app.red_mode_handles  = gobjects(app.batch.folds - 1);

            if ~isempty(app.batch.bistab_bounds)
                for m = 1:max(1, app.batch.folds - 1)
                    app.blue_mode_handles(m) = fill(app.ParameterMap, ...
                        app.batch.bistab_bounds(1, :) - 2*pi*m, ...
                        app.batch.bistab_bounds(2, :), ...
                        [0 0.4470 0.7410], ...
                        'EdgeColor', 'none', ...
                        'FaceAlpha', 0.3, ...
                        'Visible', 'off');
                    app.red_mode_handles(m)  = fill(app.ParameterMap, ...
                        app.batch.bistab_bounds(1, :) + 2*pi*m, ...
                        app.batch.bistab_bounds(2, :), ...
                        [0.6350 0.0780 0.1840], ...
                        'EdgeColor', 'none', ...
                        'FaceAlpha', 0.3, ...
                        'Visible', 'off');
                end
            end


            % Initialize scatter plots (one for each state-budget combination)
            app.sys_handles = gobjects(length(app.budget_markers), ...
                length(app.sys_states));

            for budget_idx = 1:size(app.sys_handles, 1)
                for state_idx = 1:size(app.sys_handles, 2)
                    app.sys_handles(budget_idx, state_idx) = scatter(app.ParameterMap, ...
                        [], [], 10*app.ViewMarkerSize.Value, ...
                        'Marker', app.budget_markers{budget_idx}, ...
                        'MarkerFaceColor', app.state_col{state_idx}, ...
                        'MarkerEdgeColor', app.state_col{state_idx});
                end
            end

            app.DisplayRedModes.Limits  = [0 max(1, app.batch.folds - 1)];
            app.DisplayBlueModes.Limits = [0 max(1, app.batch.folds - 1)];

            % Initialize legend
            app.map_legend = legend(app.ParameterMap, ...
                                    [app.sys_handles(2, :) app.bistab_handle app.mi_handle], ...
                                    {'Sweep', 'Fluctuating', 'CW', 'Stationary', 'Bistability', 'MI'}, ...
                                    'AutoUpdate', false);
            app.map_legend.Visible = false;


            app.SystemTable.Data  = [];
            app.sys_brush         = [];
            app.selected          = [];
            app.frame_iter_A      = struct();
            app.frame_iter_B      = struct();

            app.cache = struct('id',           [], ...
                               'state',        categorical({''}, app.sys_states, 'Protected', true), ...
                               'series',       [], ...
                               'freeze_in',    [], ...
                               'freeze_lim',   [], ...
                               'budget',       [], ...
                               'frozen',       logical([]), ...
                               'detun_start',  [], ...
                               'detun_end',    [], ...
                               'detun',        [], ...
                               'power_start',  [], ...
                               'power_end',    [], ...
                               'power',        [], ...
                               'variance',     [], ...
                               'noise',        [], ...
                               'R',            [], ...
                               'N',            [], ...
                               'T',            [], ...
                               'nT',           [], ...
                               'buffer_size',  [], ...
                               'sweep_series', []);


            refresh_cache(app);
            refresh_map(app)
            refresh_table(app)
            refresh_workspace(app)

            % Register brush
            app.sys_brush = brush(app.ParameterMap);
            app.sys_brush.color = [0.8500 0.3250 0.0980];
            app.sys_brush.ActionPostCallback = @(~, ~) refresh_selected(app);

            function refresh_selected(app)

                % Get brush data from each scatter plot
                data = false(1, length(app.cache.id));
                for h = app.sys_handles(1:end)
                    brush_data = logical(h.BrushData);

%                     assert(length(brush_data) == length(h.XData), ...
%                         "-- brush data length (%d) scatter data length (%d) mismatch --", ...
%                         length(brush_data), ...
%                         length(h.XData))

                    brush_data(isnan(h.XData)) = false;

                    data(brush_data) = true;
                end

                id = app.cache.id(data);
                change_selection(app, id);

                if ~isempty(id)
                    table_idx = find(app.SystemTable.DisplayData.Id == app.selected, 1, 'first');
                    scroll(app.SystemTable, 'row', table_idx)
                end

            end

        end


        % Change convergence tolerances and update state history of each system
        function change_tol(app, cw_tol, var_tol)

            app.batch.conv_cw_tol  = cw_tol;
            app.batch.conv_var_tol = var_tol;

            for idx = 1:length(app.batch.systems)
                for series = 1:app.batch.systems(idx).series
                    app.batch.systems(idx) = update_state(app.batch.systems(idx), ...
                        series, cw_tol, var_tol);
                end
            end

            refresh_cache(app)
            refresh_map(app)
            refresh_table(app)
            refresh_workspace(app)
            animate_selected(app, 0)

        end

    end


    % Methods used by main app
    methods (Access = private)
        
        % Get indices in system array from ids
        function ind = id2ind(app, id)
            ind = find(ismember(app.cache.id, id));
        end


        % Change selected systems
        function change_selection(app, id)
        
            if ~isempty(app.frame_timer)
                stop(app.frame_timer)
            end

            app.selected = id;
            idx = id2ind(app, app.selected);
            
            % Highlight table
            removeStyle(app.SystemTable);
            if ~isempty(app.selected)
                selected_style = uistyle('BackgroundColor', '#D95319');
                table_idx = find(ismember(app.SystemTable.Data.Id, app.selected));
                addStyle(app.SystemTable, selected_style, 'row', table_idx);
            end
            
            % Highlight map
            for h = app.sys_handles(1:end)
                h.BrushData = zeros(1, length(app.cache.id));
                h.BrushData(idx) = 1;
                h.BrushData(isnan(h.XData)) = 0;
            end

            drawnow

            % Prefill fork fields
            if ~isempty(app.selected)
                app.ForkRoundtripsSeriesField.Value = max(app.cache.R(idx));
                app.ForkStepsRoundtripField.Value   = max(app.cache.N(idx));
                app.ForkTimePointsField.Value       = max(app.cache.T(idx));
                app.ForkWindowSizeField.Value       = max(app.cache.nT(idx));
                app.DetunDeltaField.Value           = mode(app.cache.detun_end(idx) - ...
                                                           app.cache.detun_start(idx));
                app.PumpDeltaField.Value            = mode(app.cache.power_end(idx) - ...
                                                           app.cache.power_start(idx));
                app.ForkSweepSeriesField.Value      = max(app.cache.sweep_series(idx));
                app.ForkBufferSizeField.Value       = max(app.cache.buffer_size(idx));
            end
    
            refresh_workspace(app)

            % Animate systems
            if app.Autoplay.Value
                animate_selected(app, 1);
            else
                animate_selected(app, 0);
            end

        end


        % Animate selected system in side windows
        function animate_selected(app, run)

            cla(app.FieldAxesA), cla(app.SpectrumAxesA), cla(app.CWRefAxesA), cla(app.ConvergenceAxesA)
            cla(app.FieldAxesB), cla(app.SpectrumAxesB), cla(app.CWRefAxesB), cla(app.ConvergenceAxesB)

            if numel(app.selected) == 0
                return
            else
                idx = id2ind(app, app.selected(end));
            end

            app.frame_timer = timer('BusyMode', 'drop', ...
                                    'ExecutionMode', 'fixedSpacing', ...
                                    'TasksToExecute', app.cache.buffer_size(idx), ...
                                    'UserData', false, ...
                                    'Period', round(1/app.PlaybackSpeed.Value, 3));
            app.frame_iter_A = [];
            app.frame_iter_B = [];
            
            % Initialize frame iterators for window A
            [app.frame_iter_A.time, ...
             app.frame_iter_A.freq, ...
             app.frame_iter_A.res] = frame_iterators(app.batch, idx, ...
                                                     app.FieldAxesA, ...
                                                     app.SpectrumAxesA, ...
                                                     app.CWRefAxesA, ...
                                                     app.ConvergenceAxesA);

            % Initialize frame iterators for window B
            [app.frame_iter_B.time, ...
             app.frame_iter_B.freq, ...
             app.frame_iter_B.res] = frame_iterators(app.batch, idx, ...
                                                     app.FieldAxesB, ...
                                                     app.SpectrumAxesB, ...
                                                     app.CWRefAxesB, ...
                                                     app.ConvergenceAxesB);

            app.frame_timer.TimerFcn = @on_timer;
            app.frame_timer.ErrorFcn = @on_error;
            if run
                start(app.frame_timer)
            end
            
            function on_timer(timer, ~)
                
                % Timer UserData stores done flag
                if timer.UserData
                    return
                end

                % Advance frame iterator for visible tab in window A
                switch app.SysViewA.SelectedTab.Title
                    case 'Field',    done_A = app.frame_iter_A.time(1);
                    case 'Spectrum', done_A = app.frame_iter_A.freq(1);
                    case 'CW Ref',   done_A = app.frame_iter_A.res(1);
                    otherwise,       done_A = false;
                end

                % Advance frame iterator for visible tab in window B
                switch app.SysViewB.SelectedTab.Title
                    case 'Field',    done_B = app.frame_iter_B.time(1);
                    case 'Spectrum', done_B = app.frame_iter_B.freq(1);
                    case 'CW Ref',   done_B = app.frame_iter_B.res(1);
                    otherwise,       done_B = false;
                end

                timer.UserData = done_A && done_B;

            end

            function on_error(~, event)
                disp("TIMER ERROR:")
                disp(event.Type)
            end

        end

        
        % Update cache (SoA) using optional hint for altered system indices
        function refresh_cache(app, dirty_idx)   

            fields = fieldnames(app.cache);

            % No hint, update entire cache
            if ~exist('dirty_idx', 'var')
                
                % Preallocate
                for n = 1:numel(fields)
                    switch class(app.cache.((fields{n})))
                        case 'double'
                            app.cache.((fields{n})) = zeros(size(app.batch.systems));
                        case 'logical'
                            app.cache.((fields{n})) = false(size(app.batch.systems));
                        case 'categorical'
                            app.cache.((fields{n}))(length(app.batch.systems) + 1) = categorical({''});
                            app.cache.((fields{n}))(end) = [];
                    end
                end

                % Write clean values
                for idx = 1:length(app.batch.systems)
                    sys = app.batch.systems(idx);
                    for n = 1:numel(fields)
                        app.cache.((fields{n}))(idx) = sys.((fields{n}))(end);
                    end
                end

            % Update individual systems according to hint
            else
                for idx = dirty_idx
                    sys = app.batch.systems(idx);

                    % Update cache entry
                    for n = 1:numel(fields)
                        app.cache.((fields{n}))(idx) = sys.((fields{n}))(end);
                    end
                end

            end
            
        end


        % Update systems in scatter plot with optional hint of altered systems
        function refresh_map(app, dirty_idx)
           
            if ~exist('dirty_idx', 'var')
                for h = app.sys_handles(1:end)
                    set(h, 'XData', [], 'YData', [], 'BrushData', []);
                end
                dirty_idx = 1:length(app.batch.systems);
                delete(findobj(app.ParameterMap, 'Type', 'line'))
            end

            % Erase existing
            for h = app.sys_handles(1:end)
                h.XData(dirty_idx) = NaN;
                h.YData(dirty_idx) = NaN;
                h.BrushData(dirty_idx) = 0;
            end

            % Draw present
            for idx = dirty_idx

                state_idx = cast(app.cache.state(idx), "double");

                if app.cache.frozen(idx)
                    budget_idx = 1;
                elseif app.cache.budget(idx) == 0
                    budget_idx = 2;
                else
                    budget_idx = 3;
                end
                
                h = app.sys_handles(budget_idx, state_idx);
                h.XData(idx) = app.cache.detun(idx);
                h.YData(idx) = app.cache.power(idx);

                line(app.ParameterMap, ...
                     [app.cache.detun_start(idx), app.cache.detun_end(idx)], ...
                     [app.cache.power_start(idx), app.cache.power_end(idx)], ...
                     'LineWidth', 0.3, ...
                     'Color', 'k', ...
                     'PickableParts', 'none', ...
                     'Visible', app.ViewLines.Value);


            end

            drawnow nocallbacks

        end

        
        % Update table with optional hint of altered systems
        function refresh_table(app, dirty_idx)

            % Remove individual table entries
            if exist('dirty_idx', 'var') && ~isempty(app.SystemTable.Data)
                app.SystemTable.Data(ismember(app.SystemTable.Data.Id, app.cache.id(dirty_idx)), :) = [];
            
            % Remove all table entries
            else
                app.SystemTable.Data = [];
                dirty_idx = 1:length(app.batch.systems);

            end

            % Create clean subtable
            filter = (app.cache.state(dirty_idx) == 'sweep' & app.DisplaySweep.Value) | ...
                     (app.cache.state(dirty_idx) == 'fluctuating' & app.DisplayFluctuating.Value) | ...
                     (app.cache.state(dirty_idx) == 'cw' & app.DisplayCW.Value) | ...
                     (app.cache.state(dirty_idx) == 'stationary' & app.DisplayStationary.Value);

            dirty_idx = dirty_idx(filter);

            Id               = app.cache.id(dirty_idx)';
            State            = app.cache.state(dirty_idx)';
            Series           = app.cache.series(dirty_idx)';
            Budget           = app.cache.budget(dirty_idx)';
            Freeze_in        = app.cache.freeze_in(dirty_idx)';
            Detun            = app.cache.detun(dirty_idx)';
            Power            = app.cache.power(dirty_idx)';
            Variance         = app.cache.variance(dirty_idx)';
            Noise            = app.cache.noise(dirty_idx)';
            Rountrips_series = app.cache.R(dirty_idx)';
            Steps_roundtrip  = app.cache.N(dirty_idx)';
            Window_size      = app.cache.T(dirty_idx)';
            Window_points    = app.cache.nT(dirty_idx)';

            t = table(Id, State, Series, Budget, Freeze_in, Detun, Power, ...
                      Variance, Noise, Rountrips_series, Steps_roundtrip, ...
                      Window_size, Window_points);

            % Merge
            app.SystemTable.Data = [app.SystemTable.Data; t];

        end

        % Update visibility of graphical components
        function refresh_workspace(app)

            app.MainPanel.Visible = true;

            if ~isempty(app.batch) && ~isempty(app.batch.systems)
                app.ViewPanel.Visible = true;
                app.RunButton.Visible = true;
                app.ParallelExecution.Visible = true;
                app.PlaybackPanel.Visible = true;
                app.EditMenu.Visible = true;
                app.ToolsMenu.Visible = true;

            else
                app.ViewPanel.Visible = false;
                app.RunButton.Visible = false;
                app.ParallelExecution.Visible = false;
                app.PlaybackPanel.Visible = false;
            end

            if ~isempty(app.batch)
                switch app.batch.type
                    case 'LLE'
                        app.ModesPanel.Visible = false;
                    case 'Ikeda'
                        app.ModesPanel.Visible = true;
                end
            end
            
            app.BatchTypeField.Value = app.batch.type;
            app.GVDField.Value = app.batch.s;

            app.TotalBudgetField.Value = sum(app.cache.budget .* ~app.cache.frozen);
            app.ActiveSystemsField.Value = sum(app.cache.budget > 0 & ~app.cache.frozen);
            app.SelectedSystemsField.Value = numel(app.selected);
            app.TotalSystemsField.Value = length(app.batch.systems);

            if isempty(app.selected)
                app.CreatePanel.Visible = true;
                app.DisplayPanel.Visible = true;
                app.ForkPanel.Visible = false;
                app.EditPanel.Visible = false;
            else
                app.CreatePanel.Visible = false;
                app.DisplayPanel.Visible = false;
                app.ForkPanel.Visible = true;
                app.EditPanel.Visible = true;
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("impl")

        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            
            if ~isempty(app.frame_timer)
                stop(app.frame_timer)
            end
            
            [file, path] = uigetfile('*.mat');
            if file
                d = uiprogressdlg(app.UIFigure, 'Title', 'Loading batch', 'Indeterminate', 'on');
                drawnow

                addpath(path)
                app.batch = load(file);
                
                %Refocus
                drawnow;
                figure(app.UIFigure)

                init(app)
                close(d)
            end

            if ~isempty(app.frame_timer)
                start(app.frame_timer)
            end

        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            
            if ~isempty(app.frame_timer)
                stop(app.frame_timer)
            end
            
            [file, path] = uiputfile('*.mat');
            if file
                d = uiprogressdlg(app.UIFigure, 'Title', 'Saving', 'Indeterminate', 'on');
                drawnow

                batch = app.batch;
                save(path + "/" + file, '-struct', 'batch', '-v7.3')

                %Refocus
                drawnow;
                figure(app.UIFigure)

                close(d)
            end

        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
           
            if ~isempty(app.frame_timer)
                stop(app.frame_timer)
            end
            
            waitfor(new_batch_window(app));
            
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)

            app.MainPanel.Enable = 'off';

            R            = app.RoundtripsSeriesField.Value;
            N            = app.StepsRoundtripField.Value;
            T            = app.WindowSizeField.Value;
            nT           = app.TimePointsField.Value;
            detun_range  = [app.DetunStartField.Value, app.DetunEndField.Value];
            power_range  = [app.PumpStartField.Value, app.PumpEndField.Value];
            sweep_series = app.SweepSeriesField.Value;
            budget       = app.BudgetField.Value;
            buffer_size  = app.BufferSizeField.Value;
            noise        = app.NoiseField.Value;
            freeze_lim   = app.FreezeLimitField.Value;

            switch app.InitField.Value
                case 'Noise'
                    init_noise = app.InitNoiseField.Value;
                    init_field = init_noise*((rand(1, nT) - 0.5 + 1i*(rand(1, nT) - 0.5)));

                case 'Gaussian'
                    peak_power = app.PeakPowerField.Value;
                    fwhm = app.FWHMField.Value;
                    chirp = app.ChirpField.Value;
                    
                    t = T/nT*(-nT/2:nT/2-1);
                    init_field = sqrt(peak_power)*exp(-2*(1 + 1i*chirp)*log(2)*(t/fwhm).^2);
                    
                case 'sech'
                    peak_power = app.PeakPowerField.Value;
                    fwhm = app.FWHMField.Value;
                    chirp = app.ChirpField.Value;

                    t = T/nT*(-nT/2:nT/2-1);
                    k = 2*log(1 + sqrt(2));
                    init_field = sqrt(peak_power)*sech(t*k/fwhm).*exp(-1i*chirp/2*k^2);
            end

            app.batch = add_system(app.batch, init_field, R, N, T, nT, ...
                                   detun_range, power_range, sweep_series, ...
                                   budget, buffer_size, noise, freeze_lim);

            refresh_cache(app, length(app.batch.systems))
            refresh_map(app, length(app.batch.systems))
            refresh_table(app, length(app.batch.systems))
            refresh_workspace(app)

            change_selection(app, app.batch.id_cnt - 1)
            animate_selected(app, 0)

            app.MainPanel.Enable = 'on';

        end

        % Value changed function: DisplayMI
        function DisplayMIValueChanged(app, event)
            app.mi_handle.Visible = app.DisplayMI.Value;
            
        end

        % Value changed function: DisplayMultistability
        function DisplayMultistabilityValueChanged(app, event)
            app.bistab_handle.Visible = app.DisplayMultistability.Value;
            for ix = 1:(app.batch.folds - 1)
                app.blue_mode_handles(ix).Visible = app.DisplayBlueModes.Value >= ix && ...
                                                    app.DisplayMultistability.Value;
                app.red_mode_handles(ix).Visible  = app.DisplayRedModes.Value >= ix && ...
                                                    app.DisplayMultistability.Value;
            end  
            
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            app.MainPanel.Enable = 'off';

            if isempty(app.batch.systems)
                return
            end

            detun_min = min([app.cache.detun_start, app.cache.detun_end]);
            detun_max = max([app.cache.detun_start, app.cache.detun_end]);
            detun_span = [detun_min detun_max] + 0.2*(detun_max - detun_min)*[-1 1];

            power_min = min([app.cache.power_start, app.cache.power_end]);
            power_max = max([app.cache.power_start, app.cache.power_end]);
            power_span = [power_min power_max] + 0.2*(power_max - power_min)*[-1 1];

            if detun_span(1) ~= detun_span(2)
                xlim(app.ParameterMap, sort(detun_span))
            end

            if power_span(1) ~= power_span(2)
                ylim(app.ParameterMap, sort(power_span))
            end

            app.MainPanel.Enable = 'on';
        end

        % Callback function: SystemTable
        function SystemTableSelectionChanged(app, event)
            change_selection(app, app.SystemTable.Data.Id(app.SystemTable.Selection))

        end

        % Button pushed function: ForkButton
        function ForkButtonPushed(app, event)
            app.MainPanel.Enable = 'off';

            if ~isempty(app.frame_timer)
                stop(app.frame_timer)
            end

            idx_fork = id2ind(app, app.selected);

            if ~any(app.cache.state(idx_fork) == 'sweep')
                app.batch = fork_system(app.batch, ...
                                        id2ind(app, app.selected), ...
                                        app.ForkRoundtripsSeriesField.Value, ...
                                        app.ForkStepsRoundtripField.Value, ...
                                        app.ForkTimePointsField.Value, ...
                                        app.ForkWindowSizeField.Value, ...
                                        app.DetunDeltaField.Value, ...
                                        app.PumpDeltaField.Value, ...
                                        app.ForkSweepSeriesField.Value, ...
                                        app.ForkBudgetField.Value, ...
                                        app.ForkBufferSizeField.Value, ...
                                        app.ForkNoiseField.Value, ...
                                        app.ForkFreezeLimField.Value);

                idx_new = length(app.batch.systems) + (1 - length(idx_fork):0);
                
                refresh_cache(app, idx_new)
                refresh_map(app, idx_new)
                refresh_table(app, idx_new)
                refresh_workspace(app)
                change_selection(app, app.selected)
            else
                uialert(app.UIFigure, "Cannot fork from sweep state", "Invalid command")
            end

            app.MainPanel.Enable = 'on';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            if ~isempty(app.frame_timer)
                stop(app.frame_timer)
            end

            d = uiprogressdlg(app.UIFigure, 'Title', 'Simulating');
            drawnow

            idx_run = find(app.cache.budget & ~app.cache.frozen);

            total_budget = sum(app.cache.budget .* ~app.cache.frozen);
            expended_budget = 0;
            
            function progress(expended)
                expended_budget = expended_budget + expended;
                d.Value = expended_budget/total_budget;
            end

            data_queue = parallel.pool.DataQueue;
            afterEach(data_queue, @progress)

            if app.ParallelExecution.Value
                app.batch = sim_par(app.batch, data_queue);
            else
                app.batch = sim_seq(app.batch, data_queue);
            end

            refresh_cache(app, idx_run)
            refresh_table(app, idx_run)
            refresh_map(app, idx_run)
            refresh_workspace(app)
            animate_selected(app, 0)

            close(d)
            
        end

        % Value changed function: ParallelExecution
        function ParallelExecutionValueChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Title', 'Starting parallel pool', 'Indeterminate', 'on');
            drawnow

            app.par_pool = gcp;

            close(d)
            
        end

        % Button pushed function: BudgetButton
        function BudgetButtonPushed(app, event)
            app.MainPanel.Enable = 'off';
            drawnow

            app.batch = renew_system(app.batch, id2ind(app, app.selected), app.EditBudget.Value);
    
            refresh_cache(app, id2ind(app, app.selected))
            refresh_map(app, id2ind(app, app.selected))
            refresh_table(app, id2ind(app, app.selected))
            refresh_workspace(app)
            change_selection(app, app.selected)
            
            app.MainPanel.Enable = 'on';
        end

        % Button pushed function: FreezeButton
        function FreezeButtonPushed(app, event)

            app.MainPanel.Enable = 'off';
            drawnow

            app.batch = freeze_system(app.batch, id2ind(app, app.selected));
            
            refresh_cache(app, id2ind(app, app.selected))
            refresh_map(app, id2ind(app, app.selected))
            refresh_table(app, id2ind(app, app.selected))
            refresh_workspace(app)

            change_selection(app, app.selected)

            app.MainPanel.Enable = 'on';
            
        end

        % Button pushed function: UnfreezeButton
        function UnfreezeButtonPushed(app, event)
            app.MainPanel.Enable = 'off';
            drawnow

            app.batch = unfreeze_system(app.batch, id2ind(app, app.selected));

            refresh_cache(app, id2ind(app, app.selected))
            refresh_map(app, id2ind(app, app.selected))
            refresh_table(app, id2ind(app, app.selected))
            refresh_workspace(app)
            change_selection(app, app.selected)

            app.MainPanel.Enable = 'on';
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.MainPanel.Enable = 'off';
            drawnow

            idx = id2ind(app, app.selected);
            app.batch = remove_system(app.batch, idx);
            
            refresh_cache(app)
            refresh_map(app)
            refresh_table(app)
            refresh_workspace(app)
            change_selection(app, [])

            app.MainPanel.Enable = 'on';
        end

        % Value changed function: ForkStepsRoundtripField, 
        % StepsRoundtripField
        function StepsRoundtripFieldValueChanged(app, event)
            value1 = app.StepsRoundtripField.Value;
            value2 = app.ForkStepsRoundtripField.Value;
            if strcmp(app.batch.type, 'Ikeda') && (value1 ~= floor(value1) || value2 ~= floor(value2))
                uialert(app.UIFigure, "Cannot take fractional steps using the Ikeda map", "Value error");
                app.StepsRoundtripField.Value = 1;
                app.ForkStepsRoundtripField.Value = 1;
            end
 
        end

        % Button pushed function: NoiseButton
        function NoiseButtonPushed(app, event)
            app.MainPanel.Enable = 'off';
            drawnow

            app.batch = adjust_noise(app.batch, id2ind(app, app.selected), app.EditNoise.Value);
            refresh_cache(app, id2ind(app, app.selected))
            refresh_map(app, id2ind(app, app.selected))
            refresh_table(app, id2ind(app, app.selected))
            refresh_workspace(app)
            change_selection(app, app.selected)

            app.MainPanel.Enable = 'on';
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            change_selection(app, [])
            app.sys_brush.Enable = 'on';

        end

        % Value changed function: PlaybackSpeed
        function PlaybackSpeedValueChanged(app, event)
            if ~isempty(app.frame_timer)
                if isequal(app.frame_timer.Running, 'on')
                    stop(app.frame_timer)
                    app.frame_timer.Period = round(1/app.PlaybackSpeed.Value, 3);
                    start(app.frame_timer)
                else
                    app.frame_timer.Period = round(1/app.PlaybackSpeed.Value, 3);
                end
                
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            if isempty(app.frame_timer)
                animate_selected(app, 'all')

            elseif app.frame_timer.UserData
                animate_selected(app, 'all')

            elseif strcmp(app.frame_timer.Running, 'off')
                start(app.frame_timer)

            else
                stop(app.frame_timer)
                
            end
     
        end

        % Value changed function: InitField
        function InitFieldValueChanged(app, event)

            app.NoisePanel.Visible = false;
            app.PulsePanel.Visible = false;

            switch app.InitField.Value
                case 'Noise'
                    app.NoisePanel.Visible = true;
                
                case 'Gaussian'
                    app.PulsePanel.Visible = true;

                case 'sech'
                    app.PulsePanel.Visible = true;
                    app.FWHMField.Value = 2*log(1 + sqrt(2))/(sqrt(app.DetunStartField.Value));
                    app.PeakPowerField.Value = 2*app.DetunStartField.Value;
            end
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)

            click = app.ParameterMap.CurrentPoint;
            x = click(1, 1);
            y = click(1, 2);
            if x >= app.ParameterMap.XLim(1) && x <= app.ParameterMap.XLim(2) && ...
               y >= app.ParameterMap.YLim(1) && y <= app.ParameterMap.YLim(2)

                if app.FigButtonStart.Value
                    app.DetunStartField.Value = x; 
                    app.PumpStartField.Value = y;
                    app.FigButtonStart.Value = 0;
                end

                if app.FigButtonEnd.Value
                    app.DetunEndField.Value = x;
                    app.PumpEndField.Value = y;
                    app.FigButtonEnd.Value = 0;
                end

                if app.ForkFigButton.Value

                    if numel(app.selected) ~= 1
                        app.ForkFigButton.Value = 0;
                        return
                    end
                    
                    selected_ind = id2ind(app, app.selected(1));
                    detun_start = app.cache.detun_end(selected_ind);
                    power_start = app.cache.power_end(selected_ind);
    
                    app.DetunDeltaField.Value = x - detun_start;
                    app.PumpDeltaField.Value = y - power_start;

                    app.ForkFigButton.Value = 0;
                end

            end

        end

        % Value changed function: FigButtonEnd, FigButtonStart, 
        % ForkFigButton
        function FigButton_5ValueChanged(app, event)
            zoom(app.ParameterMap, 'off')
            pan(app.ParameterMap, 'off')
            brush(app.ParameterMap, 'off')
        end

        % Value changed function: ViewMarkerSize
        function ViewMarkerSizeValueChanged(app, event)
            for h = app.sys_handles(1:end)
                h.SizeData = 10*app.ViewMarkerSize.Value;
            end
            
        end

        % Value changed function: ViewLines
        function ViewLinesValueChanged(app, event)
            set(findobj(app.ParameterMap, 'Type', 'line'), ...
                'Visible', app.ViewLines.Value)

        end

        % Value changed function: DisplaySweep
        function DisplaySweepValueChanged(app, event)
            for ix = 1:size(app.sys_handles, 1)
                app.sys_handles(ix, 1).Visible = app.DisplaySweep.Value;
            end
            refresh_table(app)
            change_selection(app, [])
        end

        % Value changed function: DisplayFluctuating
        function DisplayFluctuatingValueChanged(app, event)
            for ix = 1:size(app.sys_handles, 1)
                app.sys_handles(ix, 2).Visible = app.DisplayFluctuating.Value;
            end
            refresh_table(app)
            change_selection(app, [])
        end

        % Value changed function: DisplayCW
        function DisplayCWValueChanged(app, event)
            for ix = 1:size(app.sys_handles, 1)
                app.sys_handles(ix, 3).Visible = app.DisplayCW.Value;
            end
            refresh_table(app)
            change_selection(app, [])
        end

        % Value changed function: DisplayStationary
        function DisplayStationaryValueChanged(app, event)
            for ix = 1:size(app.sys_handles, 1)
                app.sys_handles(ix, 4).Visible = app.DisplayStationary.Value;
            end
            refresh_table(app)
            change_selection(app, [])
        end

        % Callback function
        function NumericalissuesCheckBoxValueChanged(app, event)
            for ix = 1:size(app.sys_handles, 1)
                app.sys_handles(ix, 5).Visible = app.NumericalissuesCheckBox.Value;
            end
            refresh_table(app)
            change_selection(app, [])
        end

        % Menu selected function: AutoforkMenu
        function AutoforkMenuSelected(app, event)
            if ~isempty(app.frame_timer)
                stop(app.frame_timer)
            end
    
            app.autofork_options = [];
            waitfor(autofork_window(app));
            if isempty(app.autofork_options)
                return
            end

            % Begin autoforking process
            function progress(expended)
                expended_budget = expended_budget + expended;
                d.Value = expended_budget/total_budget;
            end

            it = 1;
            d = uiprogressdlg(app.UIFigure,  ...
                              'Cancelable', 'on', ...
                              'CancelText', 'Done');
            drawnow

            opt = app.autofork_options;
            

            while ~d.CancelRequested 

                % Fork eligible boundary systems
                d.Title = sprintf("Autoforking iteration %d ...", it);
                d.Indeterminate = 'on';

                idx_fork = find((app.cache.state == 'stationary') & opt.fork_stationary | ...
                                (app.cache.state == 'fluctuating') & opt.fork_fluctuating | ...
                                (app.cache.state == 'cw') & opt.fork_cw);
                dirty_idx = [];

                fork_dir = [opt.detun_dec             0;
                            opt.detun_inc             0;
                            0             opt.power_dec;
                            0             opt.power_inc];


                for n = 1:size(fork_dir, 1)

                    if all(fork_dir(n, :) == 0)
                        continue
                    end

                    idx_A = length(app.batch.systems) + 1;
                    app.batch = fan_system(app.batch, idx_fork, opt.R, opt.N, opt.T, opt.nT, ...
                                           fork_dir(n, 1), fork_dir(n, 2), opt.sweep_series, opt.budget, ...
                                           opt.buffer_size, opt.noise, opt.freeze_lim, opt.shrink_bound);
                    idx_B = length(app.batch.systems);

                    dirty_idx = [dirty_idx idx_A:idx_B];
                    refresh_cache(app, dirty_idx)

                end

                drawnow


                % Renew budgets
                [~, idx_renew] = sort(app.cache.variance .* ~app.cache.frozen, 'descend', 'MissingPlacement', 'last');
                app.batch = renew_system(app.batch, idx_renew(1:opt.renew_num), opt.renew_val);
                dirty_idx = [dirty_idx idx_renew(1:opt.renew_num)];
                refresh_cache(app, idx_renew(1:opt.renew_num))
                drawnow

                
                % Simulate
                d.Title = sprintf("Simulating %d systems in iteration %d ...", ...
                                  sum(app.cache.budget & ~app.cache.frozen),  it);
                d.Indeterminate = 'off';
                drawnow

                d.Value = 0;
                total_budget = sum(app.cache.budget .* ~app.cache.frozen);
                expended_budget = 0;

                data_queue = parallel.pool.DataQueue;
                afterEach(data_queue, @progress)

                if app.ParallelExecution.Value
                    app.batch = sim_par(app.batch, data_queue);
                else
                    app.batch = sim_seq(app.batch, data_queue);
                end

                refresh_cache(app, dirty_idx)
                refresh_table(app, dirty_idx)
                refresh_map(app, dirty_idx)
                refresh_workspace(app)

                drawnow

                it = it + 1;

            end

            close(d)

        end

        % Menu selected function: ConvergencetolerancesMenu
        function ConvergencetolerancesMenuSelected(app, event)
            if ~isempty(app.frame_timer)
                stop(app.frame_timer)
            end
    
            waitfor(conv_edit_window(app));
    
        end

        % Value changed function: DisplayBlueModes, DisplayRedModes
        function AdjacentbluemodesSpinnerValueChanged(app, event)
            if app.DisplayMultistability.Value
                for ix = 1:max(1, app.batch.folds - 1)
                    app.blue_mode_handles(ix).Visible = app.DisplayBlueModes.Value >= ix;
                    app.red_mode_handles(ix).Visible = app.DisplayRedModes.Value >= ix;
                end
                animate_selected(app, false)
            end
        end

        % Value changed function: ViewLegend
        function ViewLegendValueChanged(app, event)
            app.map_legend.Visible = app.ViewLegend.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Visible = 'off';
            app.EditMenu.Text = 'Edit';
            app.ConvergencetolerancesMenu = uimenu(app.EditMenu);
            app.ConvergencetolerancesMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvergencetolerancesMenuSelected, true);
            app.ConvergencetolerancesMenu.Text = 'Convergence tolerances';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Visible = 'off';
            app.ToolsMenu.Text = 'Tools';
            app.AutoforkMenu = uimenu(app.ToolsMenu);
            app.AutoforkMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoforkMenuSelected, true);
            app.AutoforkMenu.Text = 'Autofork';
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Visible = 'off';
            app.MainGrid = uigridlayout(app.MainPanel);
            app.ParameterMap = uiaxes(app.MainGrid);
            xlabel(app.ParameterMap, 'Pump freq. detuning')
            ylabel(app.ParameterMap, 'Norm. pump power')
            zlabel(app.ParameterMap, 'Z')
            app.CreatePanel = uipanel(app.MainGrid);
            app.CreatePanel.Title = 'Create system';
            app.CreateGrid = uigridlayout(app.CreatePanel);
            app.InitialfieldLabel = uilabel(app.CreateGrid);
            app.InitialfieldLabel.Text = 'Initial field:';
            app.InitField = uidropdown(app.CreateGrid);
            app.InitField.Items = {'Noise', 'Gaussian', 'sech'};
            app.InitField.ValueChangedFcn = createCallbackFcn(app, @InitFieldValueChanged, true);
            app.InitField.Value = 'Noise';
            app.RoundtripsperseriesLabel = uilabel(app.CreateGrid);
            app.RoundtripsperseriesLabel.Text = 'Roundtrips per series:';
            app.RoundtripsSeriesField = uieditfield(app.CreateGrid, 'numeric');
            app.RoundtripsSeriesField.Limits = [0 Inf];
            app.RoundtripsSeriesField.RoundFractionalValues = 'on';
            app.RoundtripsSeriesField.ValueDisplayFormat = '%.0f';
            app.RoundtripsSeriesField.Tooltip = {'Number of cavity roundtrips in one series (budget unit).'};
            app.RoundtripsSeriesField.Value = 3000;
            app.StepsperroundtripEditFieldLabel = uilabel(app.CreateGrid);
            app.StepsperroundtripEditFieldLabel.Text = 'Steps per roundtrip:';
            app.StepsRoundtripField = uieditfield(app.CreateGrid, 'numeric');
            app.StepsRoundtripField.Limits = [0 Inf];
            app.StepsRoundtripField.ValueDisplayFormat = '%.2f';
            app.StepsRoundtripField.ValueChangedFcn = createCallbackFcn(app, @StepsRoundtripFieldValueChanged, true);
            app.StepsRoundtripField.Tooltip = {'Number of steps in one cavity roundtrip (can assume fractional values in LLE batch).'};
            app.StepsRoundtripField.Value = 1;
            app.TimePointsField = uieditfield(app.CreateGrid, 'numeric');
            app.TimePointsField.Limits = [0 Inf];
            app.TimePointsField.RoundFractionalValues = 'on';
            app.TimePointsField.ValueDisplayFormat = '%.0f';
            app.TimePointsField.Tooltip = {'Number of points used to sample the filed, or number of mode resonances modelled.'};
            app.TimePointsField.Value = 1024;
            app.TimewindowpointsEditFieldLabel = uilabel(app.CreateGrid);
            app.TimewindowpointsEditFieldLabel.Text = 'Time window points:';
            app.WindowSizeField = uieditfield(app.CreateGrid, 'numeric');
            app.WindowSizeField.Limits = [0 Inf];
            app.WindowSizeField.Tooltip = {'Roundtrip time in normalized units.'};
            app.WindowSizeField.Value = 5000;
            app.TimewindowsizeLabel = uilabel(app.CreateGrid);
            app.TimewindowsizeLabel.Text = 'Time window size:';
            app.BufferSizeField = uieditfield(app.CreateGrid, 'numeric');
            app.BufferSizeField.Limits = [0 Inf];
            app.BufferSizeField.RoundFractionalValues = 'on';
            app.BufferSizeField.ValueDisplayFormat = '%.0f';
            app.BufferSizeField.Tooltip = {'Number of field snapshots stored in circular buffer, where each is captured with a new series.'};
            app.BufferSizeField.Value = 100;
            app.FieldbuffersizeEditFieldLabel = uilabel(app.CreateGrid);
            app.FieldbuffersizeEditFieldLabel.Text = 'Field buffer size:';
            app.BudgetField = uieditfield(app.CreateGrid, 'numeric');
            app.BudgetField.Limits = [0 Inf];
            app.BudgetField.RoundFractionalValues = 'on';
            app.BudgetField.ValueDisplayFormat = '%.0f';
            app.BudgetField.Tooltip = {'Initial budget (number of series) set to execute.'};
            app.BudgetField.Value = 100;
            app.BudgetEditFieldLabel = uilabel(app.CreateGrid);
            app.BudgetEditFieldLabel.Text = 'Budget:';
            app.FreezeLimitField = uieditfield(app.CreateGrid, 'numeric');
            app.FreezeLimitField.Limits = [0 Inf];
            app.FreezeLimitField.ValueDisplayFormat = '%.0f';
            app.FreezeLimitField.Tooltip = {'Number of equal contiguous system states tolerated before freezing system.'};
            app.FreezeLimitField.Value = 10000;
            app.FreezelimitEditFieldLabel = uilabel(app.CreateGrid);
            app.FreezelimitEditFieldLabel.Text = 'Freeze limit:';
            app.SweepSeriesField = uieditfield(app.CreateGrid, 'numeric');
            app.SweepSeriesField.Limits = [0 Inf];
            app.SweepSeriesField.RoundFractionalValues = 'on';
            app.SweepSeriesField.ValueDisplayFormat = '%.0f';
            app.SweepSeriesField.Tooltip = {'Number of series dedicated to interpolating the system parameters from initial to final values.'};
            app.SweepSeriesField.Value = 100;
            app.SweepseriesEditFieldLabel = uilabel(app.CreateGrid);
            app.SweepseriesEditFieldLabel.Text = 'Sweep series:';
            app.DetunrangeEditFieldLabel = uilabel(app.CreateGrid);
            app.DetunrangeEditFieldLabel.Text = 'Detun range:';
            app.DetunStartField = uieditfield(app.CreateGrid, 'numeric');
            app.DetunStartField.ValueDisplayFormat = '%3.2g';
            app.DetunStartField.Tooltip = {'Initial and final frequency detuning values in parameter sweep.'};
            app.DetunStartField.Value = 3e-05;
            app.DetunEndField = uieditfield(app.CreateGrid, 'numeric');
            app.DetunEndField.ValueDisplayFormat = '%3.2g';
            app.DetunEndField.Tooltip = {'Initial and final frequency detuning values in parameter sweep.'};
            app.DetunEndField.Value = 3e-05;
            app.PumprangeEditFieldLabel = uilabel(app.CreateGrid);
            app.PumprangeEditFieldLabel.Text = 'Pump range:';
            app.PumpStartField = uieditfield(app.CreateGrid, 'numeric');
            app.PumpStartField.Limits = [0 Inf];
            app.PumpStartField.ValueDisplayFormat = '%3.2g';
            app.PumpStartField.Tooltip = {'Initial and final pump power values in parameter sweep.'};
            app.PumpStartField.Value = 2e-09;
            app.PumpEndField = uieditfield(app.CreateGrid, 'numeric');
            app.PumpEndField.Limits = [0 Inf];
            app.PumpEndField.ValueDisplayFormat = '%3.2g';
            app.PumpEndField.Tooltip = {'Initial and final pump power values in parameter sweep.'};
            app.PumpEndField.Value = 2e-09;
            app.FigButtonStart = uibutton(app.CreateGrid, 'state');
            app.FigButtonStart.ValueChangedFcn = createCallbackFcn(app, @FigButton_5ValueChanged, true);
            app.FigButtonStart.Tooltip = {'Graphically select initial parameters.'};
            app.FigButtonStart.Text = 'Fig';
            app.FigButtonEnd = uibutton(app.CreateGrid, 'state');
            app.FigButtonEnd.ValueChangedFcn = createCallbackFcn(app, @FigButton_5ValueChanged, true);
            app.FigButtonEnd.Tooltip = {'Graphically select final parameters.'};
            app.FigButtonEnd.Text = 'Fig';
            app.NoiseField = uieditfield(app.CreateGrid, 'numeric');
            app.NoiseField.Limits = [0 Inf];
            app.NoiseField.Tooltip = {'Strength of noise continuously added to field with each series.'};
            app.NoiseEditFieldLabel = uilabel(app.CreateGrid);
            app.NoiseEditFieldLabel.Text = 'Noise:';
            app.CreateButton = uibutton(app.CreateGrid, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Tooltip = {'Add system to batch.'};
            app.CreateButton.Text = 'Create';
            app.PulsePanel = uipanel(app.CreateGrid);
            app.PulsePanel.Visible = 'off';
            app.PulseGrid = uigridlayout(app.PulsePanel);
            app.FWHMEditFieldLabel = uilabel(app.PulseGrid);
            app.FWHMEditFieldLabel.Text = 'FWHM:';
            app.FWHMField = uieditfield(app.PulseGrid, 'numeric');
            app.FWHMField.Limits = [0 Inf];
            app.FWHMField.Tooltip = {'Full Width at Half Maximum of pulse.'};
            app.FWHMField.Value = 200;
            app.PeakpowerEditFieldLabel = uilabel(app.PulseGrid);
            app.PeakpowerEditFieldLabel.Text = 'Peak power:';
            app.PeakPowerField = uieditfield(app.PulseGrid, 'numeric');
            app.PeakPowerField.Limits = [0 Inf];
            app.PeakPowerField.Tooltip = {'Peak power of pulse.'};
            app.PeakPowerField.Value = 0.0001;
            app.ChirpEditFieldLabel = uilabel(app.PulseGrid);
            app.ChirpEditFieldLabel.Text = 'Chirp:';
            app.ChirpField = uieditfield(app.PulseGrid, 'numeric');
            app.ChirpField.Tooltip = {'Linear frequency chirp of pulse.'};
            app.NoisePanel = uipanel(app.CreateGrid);
            app.NoiseGrid = uigridlayout(app.NoisePanel);
            app.InitnoiseEditFieldLabel = uilabel(app.NoiseGrid);
            app.InitnoiseEditFieldLabel.Text = 'Init noise:';
            app.InitNoiseField = uieditfield(app.NoiseGrid, 'numeric');
            app.InitNoiseField.Limits = [0 Inf];
            app.InitNoiseField.Tooltip = {'Strength of initial noise.'};
            app.InitNoiseField.Value = 1e-06;
            app.ForkPanel = uipanel(app.MainGrid);
            app.ForkPanel.Title = 'Fork system';
            app.ForkGrid = uigridlayout(app.ForkPanel);
            app.RoundtripsperseriesEditField_2Label = uilabel(app.ForkGrid);
            app.RoundtripsperseriesEditField_2Label.Text = 'Roundtrips per series:';
            app.ForkRoundtripsSeriesField = uieditfield(app.ForkGrid, 'numeric');
            app.ForkRoundtripsSeriesField.Limits = [0 Inf];
            app.ForkRoundtripsSeriesField.RoundFractionalValues = 'on';
            app.ForkRoundtripsSeriesField.ValueDisplayFormat = '%.0f';
            app.ForkRoundtripsSeriesField.Tooltip = {'Number of cavity roundtrips in one series (budget unit).'};
            app.ForkRoundtripsSeriesField.Value = 3000;
            app.StepsperroundtripEditField_2Label = uilabel(app.ForkGrid);
            app.StepsperroundtripEditField_2Label.Text = 'Steps per roundtrip:';
            app.ForkStepsRoundtripField = uieditfield(app.ForkGrid, 'numeric');
            app.ForkStepsRoundtripField.Limits = [0 Inf];
            app.ForkStepsRoundtripField.ValueDisplayFormat = '%.2f';
            app.ForkStepsRoundtripField.ValueChangedFcn = createCallbackFcn(app, @StepsRoundtripFieldValueChanged, true);
            app.ForkStepsRoundtripField.Tooltip = {'Number of steps in one cavity roundtrip (can assume fractional values in LLE batch).'};
            app.ForkStepsRoundtripField.Value = 1;
            app.ForkTimePointsField = uieditfield(app.ForkGrid, 'numeric');
            app.ForkTimePointsField.Limits = [0 Inf];
            app.ForkTimePointsField.Tooltip = {'Roundtrip time in normalized units.'};
            app.ForkTimePointsField.Value = 5000;
            app.TimewindowsizeLabel_2 = uilabel(app.ForkGrid);
            app.TimewindowsizeLabel_2.Tooltip = {''};
            app.TimewindowsizeLabel_2.Text = 'Time window size:';
            app.ForkWindowSizeField = uieditfield(app.ForkGrid, 'numeric');
            app.ForkWindowSizeField.Limits = [0 Inf];
            app.ForkWindowSizeField.RoundFractionalValues = 'on';
            app.ForkWindowSizeField.ValueDisplayFormat = '%.0f';
            app.ForkWindowSizeField.Tooltip = {'Number of points used to sample the filed, or number of mode resonances modelled.'};
            app.ForkWindowSizeField.Value = 1024;
            app.TimewindowpointsEditField_2Label = uilabel(app.ForkGrid);
            app.TimewindowpointsEditField_2Label.Text = 'Time window points:';
            app.ForkBufferSizeField = uieditfield(app.ForkGrid, 'numeric');
            app.ForkBufferSizeField.Limits = [0 Inf];
            app.ForkBufferSizeField.RoundFractionalValues = 'on';
            app.ForkBufferSizeField.ValueDisplayFormat = '%.0f';
            app.ForkBufferSizeField.Tooltip = {'Number of field snapshots stored in circular buffer, where each is captured with a new series.'};
            app.ForkBufferSizeField.Value = 100;
            app.FieldbuffersizeEditField_2Label = uilabel(app.ForkGrid);
            app.FieldbuffersizeEditField_2Label.Text = 'Field buffer size:';
            app.ForkBudgetField = uieditfield(app.ForkGrid, 'numeric');
            app.ForkBudgetField.Limits = [0 Inf];
            app.ForkBudgetField.RoundFractionalValues = 'on';
            app.ForkBudgetField.ValueDisplayFormat = '%.0f';
            app.ForkBudgetField.Tooltip = {'Initial budget (number of series) set to execute.'};
            app.ForkBudgetField.Value = 100;
            app.BudgetEditField_2Label = uilabel(app.ForkGrid);
            app.BudgetEditField_2Label.Text = 'Budget:';
            app.ForkFreezeLimField = uieditfield(app.ForkGrid, 'numeric');
            app.ForkFreezeLimField.Limits = [0 Inf];
            app.ForkFreezeLimField.ValueDisplayFormat = '%.0f';
            app.ForkFreezeLimField.Tooltip = {'Number of equal contiguous system states tolerated before freezing system.'};
            app.ForkFreezeLimField.Value = 10000;
            app.FreezelimitEditField_2Label = uilabel(app.ForkGrid);
            app.FreezelimitEditField_2Label.Text = 'Freeze limit:';
            app.ForkSweepSeriesField = uieditfield(app.ForkGrid, 'numeric');
            app.ForkSweepSeriesField.Limits = [0 Inf];
            app.ForkSweepSeriesField.RoundFractionalValues = 'on';
            app.ForkSweepSeriesField.ValueDisplayFormat = '%.0f';
            app.ForkSweepSeriesField.Tooltip = {'Number of series dedicated to interpolating the system parameters from initial to final values.'};
            app.ForkSweepSeriesField.Value = 100;
            app.SweepseriesEditField_2Label = uilabel(app.ForkGrid);
            app.SweepseriesEditField_2Label.Text = 'Sweep series:';
            app.DetundeltaEditFieldLabel = uilabel(app.ForkGrid);
            app.DetundeltaEditFieldLabel.Text = 'Detun delta:';
            app.DetunDeltaField = uieditfield(app.ForkGrid, 'numeric');
            app.DetunDeltaField.ValueDisplayFormat = '%3.2g';
            app.DetunDeltaField.Tooltip = {'Change to detuning over parameter sweep.'};
            app.PumpdeltaEditFieldLabel = uilabel(app.ForkGrid);
            app.PumpdeltaEditFieldLabel.Text = 'Pump delta:';
            app.PumpDeltaField = uieditfield(app.ForkGrid, 'numeric');
            app.PumpDeltaField.ValueDisplayFormat = '%3.2g';
            app.PumpDeltaField.Tooltip = {'Change to pump power over parameter sweep.'};
            app.ForkNoiseField = uieditfield(app.ForkGrid, 'numeric');
            app.ForkNoiseField.Limits = [0 Inf];
            app.ForkNoiseField.Tooltip = {'Strength of noise continuously added to field with each series.'};
            app.NoiseEditField_2Label = uilabel(app.ForkGrid);
            app.NoiseEditField_2Label.Text = 'Noise:';
            app.ForkButton = uibutton(app.ForkGrid, 'push');
            app.ForkButton.ButtonPushedFcn = createCallbackFcn(app, @ForkButtonPushed, true);
            app.ForkButton.Tooltip = {'Create new systems using selected systems initial fields.'};
            app.ForkButton.Text = 'Fork';
            app.ForkFigButton = uibutton(app.ForkGrid, 'state');
            app.ForkFigButton.ValueChangedFcn = createCallbackFcn(app, @FigButton_5ValueChanged, true);
            app.ForkFigButton.Tooltip = {'Graphically select final parameter values.'};
            app.ForkFigButton.Text = 'Fig';
            app.EditPanel = uipanel(app.MainGrid);
            app.EditPanel.Title = 'Edit system';
            app.EditGrid = uigridlayout(app.EditPanel);
            app.RemoveButton = uibutton(app.EditGrid, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Tooltip = {'Remove selected systems.'};
            app.RemoveButton.Text = 'Remove';
            app.UnfreezeButton = uibutton(app.EditGrid, 'push');
            app.UnfreezeButton.ButtonPushedFcn = createCallbackFcn(app, @UnfreezeButtonPushed, true);
            app.UnfreezeButton.Tooltip = {'Unfreeze selected systems and reset time to freeze.'};
            app.UnfreezeButton.Text = 'Unfreeze';
            app.FreezeButton = uibutton(app.EditGrid, 'push');
            app.FreezeButton.ButtonPushedFcn = createCallbackFcn(app, @FreezeButtonPushed, true);
            app.FreezeButton.Tooltip = {'Freeze selected systems.'};
            app.FreezeButton.Text = 'Freeze';
            app.NoiseButton = uibutton(app.EditGrid, 'push');
            app.NoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseButtonPushed, true);
            app.NoiseButton.Tooltip = {'Set noise of selected systems.'};
            app.NoiseButton.Text = 'Noise';
            app.Label = uilabel(app.EditGrid);
            app.Label.Text = '=';
            app.EditNoise = uieditfield(app.EditGrid, 'numeric');
            app.EditNoise.Tooltip = {'Set noise of selected systems.'};
            app.BudgetButton = uibutton(app.EditGrid, 'push');
            app.BudgetButton.ButtonPushedFcn = createCallbackFcn(app, @BudgetButtonPushed, true);
            app.BudgetButton.Tooltip = {'Increment simulation budget of selected systems.'};
            app.BudgetButton.Text = 'Budget';
            app.BudgetincLabel = uilabel(app.EditGrid);
            app.BudgetincLabel.Text = '+';
            app.EditBudget = uieditfield(app.EditGrid, 'numeric');
            app.EditBudget.Tooltip = {'Increment simulation budget of selected systems.'};
            app.DisplayPanel = uipanel(app.MainGrid);
            app.DisplayPanel.Title = 'Display';
            app.DisplayGrid = uigridlayout(app.DisplayPanel);
            app.DisplayMI = uicheckbox(app.DisplayGrid);
            app.DisplayMI.ValueChangedFcn = createCallbackFcn(app, @DisplayMIValueChanged, true);
            app.DisplayMI.Tooltip = {'Display regions with modulational instability w.r.t. LLE model.'};
            app.DisplayMI.Text = 'LLE modulational instability';
            app.DisplayMI.Value = true;
            app.DisplayMultistability = uicheckbox(app.DisplayGrid);
            app.DisplayMultistability.ValueChangedFcn = createCallbackFcn(app, @DisplayMultistabilityValueChanged, true);
            app.DisplayMultistability.Tooltip = {'Display regions with bistability.'};
            app.DisplayMultistability.Text = 'Bistability';
            app.DisplayMultistability.Value = true;
            app.DisplayFluctuating = uicheckbox(app.DisplayGrid);
            app.DisplayFluctuating.ValueChangedFcn = createCallbackFcn(app, @DisplayFluctuatingValueChanged, true);
            app.DisplayFluctuating.Tooltip = {'Filter fluctuating systems.'};
            app.DisplayFluctuating.Text = 'Fluctuating';
            app.DisplayFluctuating.Value = true;
            app.DisplayStationary = uicheckbox(app.DisplayGrid);
            app.DisplayStationary.ValueChangedFcn = createCallbackFcn(app, @DisplayStationaryValueChanged, true);
            app.DisplayStationary.Tooltip = {'Filter stationary systems.'};
            app.DisplayStationary.Text = 'Stationary';
            app.DisplayStationary.Value = true;
            app.DisplaySweep = uicheckbox(app.DisplayGrid);
            app.DisplaySweep.ValueChangedFcn = createCallbackFcn(app, @DisplaySweepValueChanged, true);
            app.DisplaySweep.Tooltip = {'Filter sweeping systems.'};
            app.DisplaySweep.Text = 'Sweep';
            app.DisplaySweep.Value = true;
            app.DisplayCW = uicheckbox(app.DisplayGrid);
            app.DisplayCW.ValueChangedFcn = createCallbackFcn(app, @DisplayCWValueChanged, true);
            app.DisplayCW.Tooltip = {'Filter CW systems.'};
            app.DisplayCW.Text = 'CW';
            app.DisplayCW.Value = true;
            app.ModesPanel = uipanel(app.DisplayGrid);
            app.ModesGrid = uigridlayout(app.ModesPanel);
            app.RedmodesSpinnerLabel = uilabel(app.ModesGrid);
            app.RedmodesSpinnerLabel.Text = {'Red modes:'; ''};
            app.DisplayRedModes = uispinner(app.ModesGrid);
            app.DisplayRedModes.Limits = [0 Inf];
            app.DisplayRedModes.RoundFractionalValues = 'on';
            app.DisplayRedModes.ValueChangedFcn = createCallbackFcn(app, @AdjacentbluemodesSpinnerValueChanged, true);
            app.DisplayRedModes.Tooltip = {'Number of bistability regions of adjacent red modes.'};
            app.BluemodesSpinnerLabel = uilabel(app.ModesGrid);
            app.BluemodesSpinnerLabel.Text = 'Blue modes:';
            app.DisplayBlueModes = uispinner(app.ModesGrid);
            app.DisplayBlueModes.Limits = [0 Inf];
            app.DisplayBlueModes.RoundFractionalValues = 'on';
            app.DisplayBlueModes.ValueChangedFcn = createCallbackFcn(app, @AdjacentbluemodesSpinnerValueChanged, true);
            app.DisplayBlueModes.Tooltip = {'Number of bistability regions of adjacent blue modes.'};
            app.SysViewA = uitabgroup(app.MainGrid);
            app.FieldTabA = uitab(app.SysViewA);
            app.FieldTabA.Title = 'Field';
            app.FieldGridA = uigridlayout(app.FieldTabA);
            app.FieldAxesA = uiaxes(app.FieldGridA);
            xlabel(app.FieldAxesA, 'Time')
            ylabel(app.FieldAxesA, 'Power')
            zlabel(app.FieldAxesA, 'Z')
            app.SpectrumTabA = uitab(app.SysViewA);
            app.SpectrumTabA.Title = 'Spectrum';
            app.SpectrumGridA = uigridlayout(app.SpectrumTabA);
            app.SpectrumAxesA = uiaxes(app.SpectrumGridA);
            xlabel(app.SpectrumAxesA, 'Freq')
            ylabel(app.SpectrumAxesA, 'dB')
            zlabel(app.SpectrumAxesA, 'Z')
            app.CWRefTabA = uitab(app.SysViewA);
            app.CWRefTabA.Title = 'CW Ref';
            app.CWRefGridA = uigridlayout(app.CWRefTabA);
            app.CWRefAxesA = uiaxes(app.CWRefGridA);
            xlabel(app.CWRefAxesA, 'Detun')
            ylabel(app.CWRefAxesA, 'Power')
            zlabel(app.CWRefAxesA, 'Z')
            app.ConvergenceTabA = uitab(app.SysViewA);
            app.ConvergenceTabA.Title = 'Convergence';
            app.ConvergenceGridA = uigridlayout(app.ConvergenceTabA);
            app.ConvergenceAxesA = uiaxes(app.ConvergenceGridA);
            xlabel(app.ConvergenceAxesA, 'Series')
            ylabel(app.ConvergenceAxesA, 'Variation')
            zlabel(app.ConvergenceAxesA, 'Z')
            app.SystemTable = uitable(app.MainGrid);
            app.SystemTable.ColumnName = {'Id'; 'State'; 'Series'; 'Budget'; 'Freeze in'; 'Detun'; 'Power'; 'Variance'; 'Noise'; 'Roundtrips series'; 'Steps roundtrip'; 'Win. size'; 'Win. pts.'};
            app.SystemTable.RowName = {};
            app.SystemTable.ColumnSortable = true;
            app.SystemTable.SelectionType = 'row';
            app.SystemTable.SelectionChangedFcn = createCallbackFcn(app, @SystemTableSelectionChanged, true);
            app.SysViewB = uitabgroup(app.MainGrid);
            app.FieldTabB = uitab(app.SysViewB);
            app.FieldTabB.Title = 'Field';
            app.FieldGridB = uigridlayout(app.FieldTabB);
            app.FieldAxesB = uiaxes(app.FieldGridB);
            xlabel(app.FieldAxesB, 'Time')
            ylabel(app.FieldAxesB, 'Power')
            zlabel(app.FieldAxesB, 'Z')
            app.SpectrumTabB = uitab(app.SysViewB);
            app.SpectrumTabB.Title = 'Spectrum';
            app.SpectrumGridB = uigridlayout(app.SpectrumTabB);
            app.SpectrumAxesB = uiaxes(app.SpectrumGridB);
            xlabel(app.SpectrumAxesB, 'Freq')
            ylabel(app.SpectrumAxesB, 'dB')
            zlabel(app.SpectrumAxesB, 'Z')
            app.CWRefTabB = uitab(app.SysViewB);
            app.CWRefTabB.Title = 'CW Ref';
            app.CWRefGridB = uigridlayout(app.CWRefTabB);
            app.CWRefAxesB = uiaxes(app.CWRefGridB);
            xlabel(app.CWRefAxesB, 'Detun')
            ylabel(app.CWRefAxesB, 'Power')
            zlabel(app.CWRefAxesB, 'Z')
            app.ConvergenceTabB = uitab(app.SysViewB);
            app.ConvergenceTabB.Title = 'Convergence';
            app.ConvergenceGridB = uigridlayout(app.ConvergenceTabB);
            app.ConvergenceAxesB = uiaxes(app.ConvergenceGridB);
            xlabel(app.ConvergenceAxesB, 'Series')
            ylabel(app.ConvergenceAxesB, 'Variation')
            zlabel(app.ConvergenceAxesB, 'Z')
            app.PlaybackPanel = uipanel(app.MainGrid);
            app.SpeedSpinnerLabel = uilabel(app.PlaybackPanel);
            app.SpeedSpinnerLabel.Text = 'Speed:';
            app.PlaybackSpeed = uispinner(app.PlaybackPanel);
            app.PlaybackSpeed.Limits = [1 Inf];
            app.PlaybackSpeed.RoundFractionalValues = 'on';
            app.PlaybackSpeed.ValueChangedFcn = createCallbackFcn(app, @PlaybackSpeedValueChanged, true);
            app.PlaybackSpeed.Tooltip = {'Playback speed.'};
            app.PlaybackSpeed.Value = 10;
            app.PlayButton = uibutton(app.PlaybackPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Tooltip = {'Playback of field in circular buffer of system.'};
            app.PlayButton.Text = 'Play';
            app.Autoplay = uicheckbox(app.PlaybackPanel);
            app.Autoplay.Tooltip = {'Automatically play selected systems.'};
            app.Autoplay.Text = 'Autoplay';
            app.ViewPanel = uipanel(app.MainGrid);
            app.SelectButton = uibutton(app.ViewPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Tooltip = {'Enable brushing of systems.'};
            app.SelectButton.Text = 'Select';
            app.ZoomButton = uibutton(app.ViewPanel, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Tooltip = {'Adjust zoom to present systems.'};
            app.ZoomButton.Text = 'Zoom to fit';
            app.MarkersizeLabel = uilabel(app.ViewPanel);
            app.MarkersizeLabel.Text = 'Marker size:';
            app.ViewMarkerSize = uispinner(app.ViewPanel);
            app.ViewMarkerSize.Limits = [1 Inf];
            app.ViewMarkerSize.ValueChangedFcn = createCallbackFcn(app, @ViewMarkerSizeValueChanged, true);
            app.ViewMarkerSize.Tooltip = {'Change size of markers representing systems.'};
            app.ViewMarkerSize.Value = 5;
            app.ViewLines = uicheckbox(app.ViewPanel);
            app.ViewLines.ValueChangedFcn = createCallbackFcn(app, @ViewLinesValueChanged, true);
            app.ViewLines.Tooltip = {'Show parameter trajectories of systems.'};
            app.ViewLines.Text = 'Lines';
            app.ViewLegend = uicheckbox(app.ViewPanel);
            app.ViewLegend.ValueChangedFcn = createCallbackFcn(app, @ViewLegendValueChanged, true);
            app.ViewLegend.Tooltip = {'Show parameter trajectories of systems.'};
            app.ViewLegend.Text = 'Legend';
            app.SimulatePanel = uipanel(app.MainGrid);
            app.SimulatePanel.Title = 'Simulate batch';
            app.SimulateGrid = uigridlayout(app.SimulatePanel);
            app.BatchtypeEditFieldLabel = uilabel(app.SimulateGrid);
            app.BatchtypeEditFieldLabel.Text = 'Batch type:';
            app.BatchTypeField = uieditfield(app.SimulateGrid, 'text');
            app.BatchTypeField.Editable = 'off';
            app.TotalbudgetLabel = uilabel(app.SimulateGrid);
            app.TotalbudgetLabel.Text = 'Total budget:';
            app.TotalBudgetField = uieditfield(app.SimulateGrid, 'numeric');
            app.TotalBudgetField.ValueDisplayFormat = '%.0f';
            app.TotalBudgetField.Editable = 'off';
            app.SelectedsystemsLabel = uilabel(app.SimulateGrid);
            app.SelectedsystemsLabel.Text = 'Selected systems:';
            app.SelectedSystemsField = uieditfield(app.SimulateGrid, 'numeric');
            app.SelectedSystemsField.ValueDisplayFormat = '%.0f';
            app.SelectedSystemsField.Editable = 'off';
            app.ActivesystemsEditField_2Label = uilabel(app.SimulateGrid);
            app.ActivesystemsEditField_2Label.Text = 'Active systems:';
            app.ActiveSystemsField = uieditfield(app.SimulateGrid, 'numeric');
            app.ActiveSystemsField.ValueDisplayFormat = '%.0f';
            app.ActiveSystemsField.Editable = 'off';
            app.TotalsystemsEditFieldLabel = uilabel(app.SimulateGrid);
            app.TotalsystemsEditFieldLabel.Text = 'Total systems:';
            app.TotalSystemsField = uieditfield(app.SimulateGrid, 'numeric');
            app.TotalSystemsField.ValueDisplayFormat = '%.0f';
            app.TotalSystemsField.Editable = 'off';
            app.RunButton = uibutton(app.SimulateGrid, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Simulate all unfrozen systems with budget.'};
            app.RunButton.Text = 'Run';
            app.ParallelExecution = uicheckbox(app.SimulateGrid);
            app.ParallelExecution.ValueChangedFcn = createCallbackFcn(app, @ParallelExecutionValueChanged, true);
            app.ParallelExecution.Tooltip = {'Enable parallel execution using the MATLAB Parallel Computing Toolbox.'};
            app.ParallelExecution.Text = 'Parallel execution';
            app.GVDsignEditFieldLabel = uilabel(app.SimulateGrid);
            app.GVDsignEditFieldLabel.Text = 'GVD sign:';
            app.GVDField = uieditfield(app.SimulateGrid, 'numeric');
            app.GVDField.ValueDisplayFormat = '%.0f';
            app.GVDField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end