classdef autofork_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainGrid                        matlab.ui.container.GridLayout
        OptionsPanel                    matlab.ui.container.Panel
        OptionsGrid                     matlab.ui.container.GridLayout
        ShrinkSlider                    matlab.ui.control.Slider
        ShrinkboundarySliderLabel       matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        ForkStationary                  matlab.ui.control.CheckBox
        ForkFluctuating                 matlab.ui.control.CheckBox
        ForkCW                          matlab.ui.control.CheckBox
        RenewalField                    matlab.ui.control.NumericEditField
        BudgetrenewalEditFieldLabel     matlab.ui.control.Label
        SystemsRenewedField             matlab.ui.control.NumericEditField
        SystemsrenewedEditFieldLabel    matlab.ui.control.Label
        NoiseField                      matlab.ui.control.NumericEditField
        NoiseEditFieldLabel             matlab.ui.control.Label
        SweepSeriesField                matlab.ui.control.NumericEditField
        SweepseriesEditFieldLabel       matlab.ui.control.Label
        FreezeLimField                  matlab.ui.control.NumericEditField
        FreezelimitEditFieldLabel       matlab.ui.control.Label
        BudgetField                     matlab.ui.control.NumericEditField
        BudgetEditFieldLabel            matlab.ui.control.Label
        BufferSizeField                 matlab.ui.control.NumericEditField
        FieldbuffersizeEditFieldLabel   matlab.ui.control.Label
        WindowPointsField               matlab.ui.control.NumericEditField
        TimewindowpointsEditFieldLabel  matlab.ui.control.Label
        WindowSizeField                 matlab.ui.control.NumericEditField
        TimewindowsizeEditFieldLabel    matlab.ui.control.Label
        StepsRoundtripField             matlab.ui.control.NumericEditField
        StepsperroundtripEditFieldLabel  matlab.ui.control.Label
        RoundtripsSeriesField           matlab.ui.control.NumericEditField
        RoundtripsperseriesEditFieldLabel  matlab.ui.control.Label
        PowerIncPanel                   matlab.ui.container.Panel
        PowerIncField                   matlab.ui.control.NumericEditField
        PowerDecPanel                   matlab.ui.container.Panel
        PowerDecField                   matlab.ui.control.NumericEditField
        DetunIncPanel                   matlab.ui.container.Panel
        DetunIncField                   matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        DetunDecPanel                   matlab.ui.container.Panel
        DetunDecField                   matlab.ui.control.NumericEditField
        DetuningdecLabel                matlab.ui.control.Label
        Map                             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainapp
        sys_handles
        bound_handle        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            hold(app.Map, 'on')
            app.sys_handles = copyobj(app.mainapp.sys_handles, app.Map);

            for idx = 1:numel(app.sys_handles)
                app.sys_handles(idx).SizeData = 10;
            end

            points = [app.mainapp.cache.detun; ...
                      app.mainapp.cache.power];
            bound = boundary(points', app.ShrinkSlider.Value);
            app.bound_handle = fill(app.Map, points(1, bound), points(2, bound), ...
                                    [0 0.4470 0.7410], 'FaceAlpha', 0.3);

            % Prefill fields
            app.RoundtripsSeriesField.Value = mode(mainapp.cache.R);
            app.StepsRoundtripField.Value   = max(mainapp.cache.N);
            app.WindowSizeField.Value       = mode(mainapp.cache.T);
            app.WindowPointsField.Value     = max(mainapp.cache.nT);
            app.BufferSizeField.Value       = mode(mainapp.cache.buffer_size);
            app.FreezeLimField.Value        = mode(mainapp.cache.freeze_lim);
            app.SweepSeriesField.Value      = mode(mainapp.cache.sweep_series);
            app.NoiseField.Value            = mode(mainapp.cache.noise);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.mainapp.autofork_options = struct( ...
                'R',                app.RoundtripsSeriesField.Value, ...
                'N',                app.StepsRoundtripField.Value, ...
                'T',                app.WindowSizeField.Value, ...
                'nT',               app.WindowPointsField.Value, ...
                'buffer_size',      app.BufferSizeField.Value, ...
                'budget',           app.BudgetField.Value, ...
                'freeze_lim',       app.FreezeLimField.Value, ...
                'sweep_series',     app.SweepSeriesField.Value, ...
                'noise',            app.NoiseField.Value, ...
                'renew_num',        app.SystemsRenewedField.Value, ...
                'renew_val',        app.RenewalField.Value, ...
                'shrink_bound',     app.ShrinkSlider.Value, ...
                'fork_stationary',  app.ForkStationary.Value, ...
                'fork_fluctuating', app.ForkFluctuating.Value, ...
                'fork_cw',          app.ForkCW.Value, ...
                'detun_dec',        app.DetunDecField.Value, ...
                'detun_inc',        app.DetunIncField.Value, ...
                'power_dec',        app.PowerDecField.Value, ...
                'power_inc',        app.PowerIncField.Value);

            delete(app)
        end

        % Value changed function: ShrinkSlider
        function ShrinkSliderValueChanged(app, event)
            points = [app.mainapp.cache.detun; ...
                      app.mainapp.cache.power];
            bound = boundary(points', app.ShrinkSlider.Value);
            delete(app.bound_handle)
            app.bound_handle = fill(app.Map, points(1, bound), points(2, bound), ...
                                    [0 0.4470 0.7410], 'FaceAlpha', 0.3);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGrid = uigridlayout(app.UIFigure);
            app.Map = uiaxes(app.MainGrid);
            app.DetunDecPanel = uipanel(app.MainGrid);
            app.DetuningdecLabel = uilabel(app.DetunDecPanel);
            app.DetuningdecLabel.Text = '';
            app.DetunDecField = uieditfield(app.DetunDecPanel, 'numeric');
            app.DetunDecField.LowerLimitInclusive = 'off';
            app.DetunDecField.Limits = [-Inf 0];
            app.DetunDecField.Tooltip = {'Detuning decrement'};
            app.DetunIncPanel = uipanel(app.MainGrid);
            app.EditField_2Label = uilabel(app.DetunIncPanel);
            app.EditField_2Label.Text = '';
            app.DetunIncField = uieditfield(app.DetunIncPanel, 'numeric');
            app.DetunIncField.UpperLimitInclusive = 'off';
            app.DetunIncField.Limits = [0 Inf];
            app.DetunIncField.Tooltip = {'Detuning increment'};
            app.PowerDecPanel = uipanel(app.MainGrid);
            app.PowerDecField = uieditfield(app.PowerDecPanel, 'numeric');
            app.PowerDecField.LowerLimitInclusive = 'off';
            app.PowerDecField.Limits = [-Inf 0];
            app.PowerDecField.Tooltip = {'Pump power decrement'};
            app.PowerIncPanel = uipanel(app.MainGrid);
            app.PowerIncField = uieditfield(app.PowerIncPanel, 'numeric');
            app.PowerIncField.UpperLimitInclusive = 'off';
            app.PowerIncField.Limits = [0 Inf];
            app.PowerIncField.Tooltip = {'Pump power increment'};
            app.OptionsPanel = uipanel(app.MainGrid);
            app.OptionsGrid = uigridlayout(app.OptionsPanel);
            app.RoundtripsperseriesEditFieldLabel = uilabel(app.OptionsGrid);
            app.RoundtripsperseriesEditFieldLabel.Text = 'Roundtrips per series:';
            app.RoundtripsSeriesField = uieditfield(app.OptionsGrid, 'numeric');
            app.RoundtripsSeriesField.Tooltip = {'Number of cavity roundtrips in one series (budget unit).'};
            app.StepsperroundtripEditFieldLabel = uilabel(app.OptionsGrid);
            app.StepsperroundtripEditFieldLabel.Text = 'Steps per roundtrip:';
            app.StepsRoundtripField = uieditfield(app.OptionsGrid, 'numeric');
            app.StepsRoundtripField.Tooltip = {'Number of steps in one cavity roundtrip (can assume fractional values in LLE batch).'};
            app.TimewindowsizeEditFieldLabel = uilabel(app.OptionsGrid);
            app.TimewindowsizeEditFieldLabel.Text = 'Time window size:';
            app.WindowSizeField = uieditfield(app.OptionsGrid, 'numeric');
            app.WindowSizeField.Tooltip = {'Roundtrip time in normalized units.'};
            app.TimewindowpointsEditFieldLabel = uilabel(app.OptionsGrid);
            app.TimewindowpointsEditFieldLabel.Text = 'Time window points:';
            app.WindowPointsField = uieditfield(app.OptionsGrid, 'numeric');
            app.WindowPointsField.Tooltip = {'Number of points used to sample the filed, or number of mode resonances modelled.'};
            app.FieldbuffersizeEditFieldLabel = uilabel(app.OptionsGrid);
            app.FieldbuffersizeEditFieldLabel.Text = 'Field buffer size:';
            app.BufferSizeField = uieditfield(app.OptionsGrid, 'numeric');
            app.BufferSizeField.Tooltip = {'Number of field snapshots stored in circular buffer, where each is captured with a new series.'};
            app.BudgetEditFieldLabel = uilabel(app.OptionsGrid);
            app.BudgetEditFieldLabel.Text = 'Budget:';
            app.BudgetField = uieditfield(app.OptionsGrid, 'numeric');
            app.BudgetField.Tooltip = {'Initial budget (number of series) set to execute for each system.'};
            app.BudgetField.Value = 100;
            app.FreezelimitEditFieldLabel = uilabel(app.OptionsGrid);
            app.FreezelimitEditFieldLabel.Text = 'Freeze limit:';
            app.FreezeLimField = uieditfield(app.OptionsGrid, 'numeric');
            app.FreezeLimField.Tooltip = {'Number of equal contiguous system states tolerated before freezing system.'};
            app.SweepseriesEditFieldLabel = uilabel(app.OptionsGrid);
            app.SweepseriesEditFieldLabel.Text = 'Sweep series:';
            app.SweepSeriesField = uieditfield(app.OptionsGrid, 'numeric');
            app.SweepSeriesField.Tooltip = {'Number of series dedicated to interpolating the system parameters from initial to final values.'};
            app.NoiseEditFieldLabel = uilabel(app.OptionsGrid);
            app.NoiseEditFieldLabel.Text = 'Noise:';
            app.NoiseField = uieditfield(app.OptionsGrid, 'numeric');
            app.NoiseField.Tooltip = {'Strength of noise continuously added to field with each series.'};
            app.SystemsrenewedEditFieldLabel = uilabel(app.OptionsGrid);
            app.SystemsrenewedEditFieldLabel.Text = 'Systems renewed:';
            app.SystemsRenewedField = uieditfield(app.OptionsGrid, 'numeric');
            app.SystemsRenewedField.Tooltip = {'Number of nonfrozen systems with largest variance picked for budget renewal each iteration'};
            app.BudgetrenewalEditFieldLabel = uilabel(app.OptionsGrid);
            app.BudgetrenewalEditFieldLabel.Text = 'Budget renewal:';
            app.RenewalField = uieditfield(app.OptionsGrid, 'numeric');
            app.RenewalField.Tooltip = {'Budget added to picked systems each iteration.'};
            app.ForkCW = uicheckbox(app.OptionsGrid);
            app.ForkCW.Tooltip = {'Allow CW systems to fork'};
            app.ForkCW.Text = 'Fork cw';
            app.ForkFluctuating = uicheckbox(app.OptionsGrid);
            app.ForkFluctuating.Tooltip = {'Allow fluctuating systems to fork'};
            app.ForkFluctuating.Text = 'Fork fluctuating';
            app.ForkStationary = uicheckbox(app.OptionsGrid);
            app.ForkStationary.Tooltip = {'Allow stationary systems to fork'};
            app.ForkStationary.Text = 'Fork stationary';
            app.ForkStationary.Value = true;
            app.StartButton = uibutton(app.OptionsGrid, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ShrinkboundarySliderLabel = uilabel(app.OptionsGrid);
            app.ShrinkboundarySliderLabel.Text = 'Shrink boundary';
            app.ShrinkSlider = uislider(app.OptionsGrid);
            app.ShrinkSlider.Limits = [0 1];
            app.ShrinkSlider.MajorTicks = [0 0.5 1];
            app.ShrinkSlider.ValueChangedFcn = createCallbackFcn(app, @ShrinkSliderValueChanged, true);
            app.ShrinkSlider.Tooltip = {'Tightness of boundary from which systems are forked'};
            app.ShrinkSlider.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autofork_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end