classdef conv_edit_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        ApplyButton                  matlab.ui.control.Button
        variationtoleranceEditFieldLabel  matlab.ui.control.Label
        variationtoleranceEditField  matlab.ui.control.NumericEditField
        cwtoleranceEditField         matlab.ui.control.NumericEditField
        cwtoleranceLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            
            app.cwtoleranceEditField.Value        = mainapp.batch.conv_cw_tol;
            app.variationtoleranceEditField.Value = mainapp.batch.conv_var_tol;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            change_tol(app.mainapp, app.cwtoleranceEditField.Value, ...
                                    app.variationtoleranceEditField.Value)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.cwtoleranceLabel = uilabel(app.GridLayout);
            app.cwtoleranceLabel.Text = 'cw tolerance:';
            app.cwtoleranceEditField = uieditfield(app.GridLayout, 'numeric');
            app.cwtoleranceEditField.Limits = [0 Inf];
            app.cwtoleranceEditField.Value = 1;
            app.variationtoleranceEditField = uieditfield(app.GridLayout, 'numeric');
            app.variationtoleranceEditField.Limits = [0 Inf];
            app.variationtoleranceEditField.Value = 1;
            app.variationtoleranceEditFieldLabel = uilabel(app.GridLayout);
            app.variationtoleranceEditFieldLabel.Text = 'variation tolerance:';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = conv_edit_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end