classdef new_batch_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModelTabs                       matlab.ui.container.TabGroup
        LLETab                          matlab.ui.container.Tab
        LLEGrid                         matlab.ui.container.GridLayout
        GenLLEButton                    matlab.ui.control.Button
        LLEVarTol                       matlab.ui.control.NumericEditField
        ConvergencecwtolLabel_6         matlab.ui.control.Label
        LLECWTol                        matlab.ui.control.NumericEditField
        ConvergencecwtolLabel_4         matlab.ui.control.Label
        cwPowerLevels                   matlab.ui.control.NumericEditField
        CWsolverpowerlevelsEditField_3Label  matlab.ui.control.Label
        LLEPumpLevels                   matlab.ui.control.NumericEditField
        PumppowerlevelsEditField_3Label  matlab.ui.control.Label
        LLEPumpEnd                      matlab.ui.control.NumericEditField
        LLEPumpStart                    matlab.ui.control.NumericEditField
        PumppowerrangeEditField_3Label  matlab.ui.control.Label
        LLEFinesse                      matlab.ui.control.NumericEditField
        ResonatorfinesseEditField_3Label  matlab.ui.control.Label
        LLEDisp                         matlab.ui.control.DropDown
        GroupvelocitydispersionDropDown_3Label  matlab.ui.control.Label
        IkedaTab                        matlab.ui.container.Tab
        IkedaGrid                       matlab.ui.container.GridLayout
        GenIkedaButton                  matlab.ui.control.Button
        IkedaVarTol                     matlab.ui.control.NumericEditField
        ConvergencecwtolLabel_3         matlab.ui.control.Label
        IkedaCWTol                      matlab.ui.control.NumericEditField
        ConvergencecwtolLabel           matlab.ui.control.Label
        cwCurveTol                      matlab.ui.control.NumericEditField
        CWsolvercurvetolEditFieldLabel  matlab.ui.control.Label
        cwNewtonTol                     matlab.ui.control.NumericEditField
        CWsolverNewtontolEditFieldLabel  matlab.ui.control.Label
        IkedaPumpLevels                 matlab.ui.control.NumericEditField
        PumppowerlevelsEditField_2Label  matlab.ui.control.Label
        IkedaPumpEnd                    matlab.ui.control.NumericEditField
        IkedaPumpStart                  matlab.ui.control.NumericEditField
        PumppowerrangeEditField_2Label  matlab.ui.control.Label
        IkedaFinesse                    matlab.ui.control.NumericEditField
        ResonatorfinesseEditField_2Label  matlab.ui.control.Label
        IkedaDisp                       matlab.ui.control.DropDown
        GroupvelocitydispersionDropDown_2Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
        end

        % Button pushed function: GenLLEButton
        function GenLLEButtonPushed(app, event)

            d = uiprogressdlg(app.UIFigure, 'Title', 'Generating LLE batch', 'Indeterminate', 'on');
            drawnow

            switch app.LLEDisp.Value
                case 'Normal',    sgn = 1;
                case 'Anomalous', sgn = -1;
            end

            app.mainapp.batch = LLE_batch(...
                app.LLEFinesse.Value, ...
                sgn, ...
                [app.LLEPumpStart.Value app.LLEPumpEnd.Value], ...
                app.LLEPumpLevels.Value, ...
                app.cwPowerLevels.Value, ...
                app.LLECWTol.Value, ...
                app.LLEVarTol.Value);

            app.mainapp.init()

            close(d)
            delete(app)
        end

        % Button pushed function: GenIkedaButton
        function GenIkedaButtonPushed(app, event)

            d = uiprogressdlg(app.UIFigure, 'Title', 'Generating Ikeda batch', 'Indeterminate', 'on');
            drawnow

            switch app.IkedaDisp.Value
                case 'Normal',    sgn = 1;
                case 'Anomalous', sgn = -1;
            end

            app.mainapp.batch = Ikeda_batch(...
                app.IkedaFinesse.Value, ...
                sgn, ...
                [app.IkedaPumpStart.Value app.IkedaPumpEnd.Value], ...
                app.IkedaPumpLevels.Value, ...
                app.cwNewtonTol.Value, ...
                app.cwCurveTol.Value, ...
                app.IkedaCWTol.Value, ...
                app.IkedaVarTol.Value);

            app.mainapp.init()

            close(d)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModelTabs = uitabgroup(app.UIFigure);
            app.LLETab = uitab(app.ModelTabs);
            app.LLETab.Title = 'LLE';
            app.LLEGrid = uigridlayout(app.LLETab);
            app.GroupvelocitydispersionDropDown_3Label = uilabel(app.LLEGrid);
            app.GroupvelocitydispersionDropDown_3Label.Text = 'Group-velocity dispersion:';
            app.LLEDisp = uidropdown(app.LLEGrid);
            app.LLEDisp.Items = {'Anomalous', 'Normal'};
            app.LLEDisp.Value = 'Anomalous';
            app.ResonatorfinesseEditField_3Label = uilabel(app.LLEGrid);
            app.ResonatorfinesseEditField_3Label.Text = 'Resonator finesse:';
            app.LLEFinesse = uieditfield(app.LLEGrid, 'numeric');
            app.LLEFinesse.Limits = [0 Inf];
            app.LLEFinesse.ValueDisplayFormat = '%5.1e';
            app.LLEFinesse.Value = 100000;
            app.PumppowerrangeEditField_3Label = uilabel(app.LLEGrid);
            app.PumppowerrangeEditField_3Label.Text = 'Pump power range:';
            app.LLEPumpStart = uieditfield(app.LLEGrid, 'numeric');
            app.LLEPumpStart.Limits = [0 Inf];
            app.LLEPumpStart.ValueDisplayFormat = '%5.1e';
            app.LLEPumpStart.Tooltip = {'Range of pump power values plotted in the parameter map.'};
            app.LLEPumpStart.Value = 1e-11;
            app.LLEPumpEnd = uieditfield(app.LLEGrid, 'numeric');
            app.LLEPumpEnd.Limits = [0 Inf];
            app.LLEPumpEnd.ValueDisplayFormat = '%5.1e';
            app.LLEPumpEnd.Tooltip = {'Range of pump power values plotted in the parameter map.'};
            app.LLEPumpEnd.Value = 1e-06;
            app.PumppowerlevelsEditField_3Label = uilabel(app.LLEGrid);
            app.PumppowerlevelsEditField_3Label.Text = 'Pump power levels:';
            app.LLEPumpLevels = uieditfield(app.LLEGrid, 'numeric');
            app.LLEPumpLevels.Limits = [0 Inf];
            app.LLEPumpLevels.RoundFractionalValues = 'on';
            app.LLEPumpLevels.ValueDisplayFormat = '%.0f';
            app.LLEPumpLevels.Tooltip = {'Number of pump power levels used to plot the parameter map.'};
            app.LLEPumpLevels.Value = 1000;
            app.CWsolverpowerlevelsEditField_3Label = uilabel(app.LLEGrid);
            app.CWsolverpowerlevelsEditField_3Label.Text = 'CW solver power levels:';
            app.cwPowerLevels = uieditfield(app.LLEGrid, 'numeric');
            app.cwPowerLevels.Limits = [0 Inf];
            app.cwPowerLevels.RoundFractionalValues = 'on';
            app.cwPowerLevels.ValueDisplayFormat = '%.0f';
            app.cwPowerLevels.Tooltip = {'The number of power levels used inside the LLE CW solver. Increase to improve accuracy of CW references used in the batch.'};
            app.cwPowerLevels.Value = 10000;
            app.ConvergencecwtolLabel_4 = uilabel(app.LLEGrid);
            app.ConvergencecwtolLabel_4.Text = 'Convergence CW tol:';
            app.LLECWTol = uieditfield(app.LLEGrid, 'numeric');
            app.LLECWTol.Limits = [0 Inf];
            app.LLECWTol.ValueDisplayFormat = '%5.1e';
            app.LLECWTol.Tooltip = {'Tolerance used to classify CW states. Can be edited from the ''Edit'' tab of the main application.'};
            app.LLECWTol.Value = 0.001;
            app.ConvergencecwtolLabel_6 = uilabel(app.LLEGrid);
            app.ConvergencecwtolLabel_6.Text = 'Convergence var tol:';
            app.LLEVarTol = uieditfield(app.LLEGrid, 'numeric');
            app.LLEVarTol.Limits = [0 Inf];
            app.LLEVarTol.ValueDisplayFormat = '%5.1e';
            app.LLEVarTol.Tooltip = {'Tolerance used to classify stationary states. Can be edited from the ''Edit'' tab of the main application.'};
            app.LLEVarTol.Value = 1e-05;
            app.GenLLEButton = uibutton(app.LLEGrid, 'push');
            app.GenLLEButton.ButtonPushedFcn = createCallbackFcn(app, @GenLLEButtonPushed, true);
            app.GenLLEButton.Text = 'Generate LLE Batch';
            app.IkedaTab = uitab(app.ModelTabs);
            app.IkedaTab.Title = 'Ikeda';
            app.IkedaGrid = uigridlayout(app.IkedaTab);
            app.GroupvelocitydispersionDropDown_2Label = uilabel(app.IkedaGrid);
            app.GroupvelocitydispersionDropDown_2Label.Text = 'Group-velocity dispersion:';
            app.IkedaDisp = uidropdown(app.IkedaGrid);
            app.IkedaDisp.Items = {'Anomalous', 'Normal'};
            app.IkedaDisp.Value = 'Anomalous';
            app.ResonatorfinesseEditField_2Label = uilabel(app.IkedaGrid);
            app.ResonatorfinesseEditField_2Label.Text = 'Resonator finesse:';
            app.IkedaFinesse = uieditfield(app.IkedaGrid, 'numeric');
            app.IkedaFinesse.Limits = [0 Inf];
            app.IkedaFinesse.ValueDisplayFormat = '%5.1e';
            app.IkedaFinesse.Value = 100000;
            app.PumppowerrangeEditField_2Label = uilabel(app.IkedaGrid);
            app.PumppowerrangeEditField_2Label.Text = 'Pump power range:';
            app.IkedaPumpStart = uieditfield(app.IkedaGrid, 'numeric');
            app.IkedaPumpStart.Limits = [0 Inf];
            app.IkedaPumpStart.ValueDisplayFormat = '%5.1e';
            app.IkedaPumpStart.Tooltip = {'Range of pump power values plotted in the parameter map.'};
            app.IkedaPumpStart.Value = 1e-11;
            app.IkedaPumpEnd = uieditfield(app.IkedaGrid, 'numeric');
            app.IkedaPumpEnd.Limits = [0 Inf];
            app.IkedaPumpEnd.ValueDisplayFormat = '%5.1e';
            app.IkedaPumpEnd.Tooltip = {'Range of pump power values plotted in the parameter map.'};
            app.IkedaPumpEnd.Value = 0.001;
            app.PumppowerlevelsEditField_2Label = uilabel(app.IkedaGrid);
            app.PumppowerlevelsEditField_2Label.Text = 'Pump power levels:';
            app.IkedaPumpLevels = uieditfield(app.IkedaGrid, 'numeric');
            app.IkedaPumpLevels.Limits = [0 Inf];
            app.IkedaPumpLevels.RoundFractionalValues = 'on';
            app.IkedaPumpLevels.ValueDisplayFormat = '%.0f';
            app.IkedaPumpLevels.Tooltip = {'Number of pump power levels used to plot the parameter map.'};
            app.IkedaPumpLevels.Value = 1000;
            app.CWsolverNewtontolEditFieldLabel = uilabel(app.IkedaGrid);
            app.CWsolverNewtontolEditFieldLabel.Text = 'CW solver Newton tol:';
            app.cwNewtonTol = uieditfield(app.IkedaGrid, 'numeric');
            app.cwNewtonTol.Limits = [0 Inf];
            app.cwNewtonTol.ValueDisplayFormat = '%5.1e';
            app.cwNewtonTol.Tooltip = {'Tolerance used for the Newton iteration algorithm in Ikeda CW solver. Use lower value for increased accuracy.'};
            app.cwNewtonTol.Value = 1e-09;
            app.CWsolvercurvetolEditFieldLabel = uilabel(app.IkedaGrid);
            app.CWsolvercurvetolEditFieldLabel.Text = 'CW solver curve tol:';
            app.cwCurveTol = uieditfield(app.IkedaGrid, 'numeric');
            app.cwCurveTol.Limits = [0 Inf];
            app.cwCurveTol.ValueDisplayFormat = '%5.1e';
            app.cwCurveTol.Tooltip = {'Tolerance for CW solution curvature before adding more points. Use lower value to increase sampling points of CW solutions.'};
            app.cwCurveTol.Value = 1e-12;
            app.ConvergencecwtolLabel = uilabel(app.IkedaGrid);
            app.ConvergencecwtolLabel.Text = 'Convergence cw tol:';
            app.IkedaCWTol = uieditfield(app.IkedaGrid, 'numeric');
            app.IkedaCWTol.Limits = [0 Inf];
            app.IkedaCWTol.ValueDisplayFormat = '%5.1e';
            app.IkedaCWTol.Tooltip = {'Tolerance used to classify CW states. Can be edited from the ''Edit'' tab of the main application.'};
            app.IkedaCWTol.Value = 0.001;
            app.ConvergencecwtolLabel_3 = uilabel(app.IkedaGrid);
            app.ConvergencecwtolLabel_3.Tooltip = {'Tolerance used to classify stationary states. Can be edited from the ''Edit'' tab of the main application.'};
            app.ConvergencecwtolLabel_3.Text = 'Convergence var tol:';
            app.IkedaVarTol = uieditfield(app.IkedaGrid, 'numeric');
            app.IkedaVarTol.Limits = [0 Inf];
            app.IkedaVarTol.ValueDisplayFormat = '%5.1e';
            app.IkedaVarTol.Tooltip = {'Tolerance used to classify stationary states. Can be edited from the ''Edit'' tab of the main application.'};
            app.IkedaVarTol.Value = 1e-05;
            app.GenIkedaButton = uibutton(app.IkedaGrid, 'push');
            app.GenIkedaButton.ButtonPushedFcn = createCallbackFcn(app, @GenIkedaButtonPushed, true);
            app.GenIkedaButton.Text = 'Generate Ikeda Batch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_batch_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end